classdef csbenchlab_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnvironmentPanel              matlab.ui.container.Panel
        NewcontrolenvironmentButton   matlab.ui.control.Button
        OpencontrolenvironmentButton  matlab.ui.control.Button
        RecentenvironmentsLabel       matlab.ui.control.Label
        RecentEnvs                    matlab.ui.control.ListBox
        PluginmanagerButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        RecentEnvsData
        RecentsFile
    end
    
    methods (Access = private)
        
        function results = load_recent_envs(app)
            path = get_appdata_path(); 
            app_root = fullfile(path, 'csbenchlab_app');
            app.RecentsFile = fullfile(app_root, 'recent_envs.mat');
            if ~exist(app_root, 'file')
                mkdir(app_root);
            end
            

            if ~exist(fullfile(app.RecentsFile), 'file')
                env_info = struct('name', {}, 'path', {});
                save(app.RecentsFile, 'env_info')
                return
            end

            info = load(app.RecentsFile, 'env_info').env_info;
            app.RecentEnvsData = info;
            app.update_recents();
        end
        
        function results = add_recent_env(app, name, path)
            env_info = load(app.RecentsFile, 'env_info').env_info;
            for i=1:length(env_info)
                if strcmp(name, env_info(i).name)
                    env_info(i) = [];
                    break
                end
            end
            info.name = name;
            info.path = path;
            env_info = [info; env_info];
            save(app.RecentsFile, 'env_info');
            app.RecentEnvsData = env_info;
            app.update_recents();
        end

        function update_recents(app)
            items = {app.RecentEnvsData.name};

            if ~isempty(items)                
                app.RecentEnvs.Items = items;
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function on_start(app)
            app.load_recent_envs();
        end

        % Button pushed function: PluginmanagerButton
        function on_open_plugin_manager(app, event)
            run('plugin_manager_app');
        end

        % Button pushed function: NewcontrolenvironmentButton
        function on_create_control_env(app, event)
            answer = inputdlg('Name', 'Control environment name');
            if isempty(answer)
                return
            end
            
            if ~isvarname(answer{1})
                error(strcat(answer{1}, " is not a valid Matlab variable name."));
            end

            path = uigetdir;
            if isnumeric(path)
                return
            end
            env_path = create_environment(answer{1}, 'Path', path);
            name = answer{1};
            if ~isempty(env_path)
                app.add_recent_env(name, fullfile(path, name));
                env_manager_app(name, env_path);
            end
            
            
        end

        % Button pushed function: OpencontrolenvironmentButton
        function on_open_control_env(app, event)
            
            [env_file, location] = uigetfile('*.cse');
            if isnumeric(env_file)
                return 
            end
            env_file = fullfile(location, env_file);
            if isempty(env_file)
                return
            end
            [path, name, ext] = fileparts(env_file);
            
            if ~strcmp(ext, '.cse')
                return
            end
            addpath(path);
            addpath(fullfile(path, name));
            addpath(fullfile(path, 'autogen'));

            app.add_recent_env(name, path);
            env_manager_app(name, path);
        end

        % Double-clicked callback: RecentEnvs
        function on_recent_env_db_click(app, event)
            item = event.InteractionInformation.Item;
            path = app.RecentEnvsData(item).path;
            name = app.RecentEnvsData(item).name;
            if ~exist(path, 'dir')
                warning("Selected environment does not exist");
                return
            end
            app.add_recent_env(name, path);
            addpath(path);
            addpath(fullfile(path, name));
            addpath(fullfile(path, 'autogen'));
            env_manager_app(name, path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PluginmanagerButton = uibutton(app.UIFigure, 'push');
            app.PluginmanagerButton.ButtonPushedFcn = createCallbackFcn(app, @on_open_plugin_manager, true);
            app.PluginmanagerButton.Text = 'Plugin manager';
            app.EnvironmentPanel = uipanel(app.UIFigure);
            app.EnvironmentPanel.Title = 'Environment';
            app.RecentEnvs = uilistbox(app.EnvironmentPanel);
            app.RecentEnvs.Items = {};
            app.RecentEnvs.DoubleClickedFcn = createCallbackFcn(app, @on_recent_env_db_click, true);
            app.RecentEnvs.Value = {};
            app.RecentenvironmentsLabel = uilabel(app.EnvironmentPanel);
            app.RecentenvironmentsLabel.Text = 'Recent environments';
            app.OpencontrolenvironmentButton = uibutton(app.EnvironmentPanel, 'push');
            app.OpencontrolenvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @on_open_control_env, true);
            app.OpencontrolenvironmentButton.Text = {'Open control '; 'environment'};
            app.NewcontrolenvironmentButton = uibutton(app.EnvironmentPanel, 'push');
            app.NewcontrolenvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @on_create_control_env, true);
            app.NewcontrolenvironmentButton.Text = {'New control '; 'environment'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = csbenchlab_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @on_start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end