classdef edit_callbacks_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        OnStopEditField            matlab.ui.control.EditField
        OnStopEditFieldLabel       matlab.ui.control.Label
        OnStartEditField           matlab.ui.control.EditField
        OnStartEditFieldLabel      matlab.ui.control.Label
        OnModelSaveEditField       matlab.ui.control.EditField
        OnModelLoadEditField       matlab.ui.control.EditField
        OnModelLoadEditFieldLabel  matlab.ui.control.Label
        OnModelSaveEditFieldLabel  matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        CancelButton               matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        OnClosedHandle
        Callbacks
    end
    
    methods (Access = private)
        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callbacks, on_closed_handle)
            app.OnClosedHandle = on_closed_handle;
            app.Callbacks = callbacks;

            app.OnModelLoadEditField.Value = callbacks.OnEnvLoad;
            app.OnModelSaveEditField.Value = callbacks.OnEnvSave;
            app.OnStartEditField.Value = callbacks.OnStart;
            app.OnStopEditField.Value = callbacks.OnStop;
            
        end

        % Button pushed function: SaveButton
        function on_select_var(app, event)
            
            app.Callbacks.OnModelLoad = app.OnModelLoadEditField.Value;
            app.Callbacks.OnModelSave = app.OnModelSaveEditField.Value;
            app.Callbacks.OnStart = app.OnStartEditField.Value;
            app.Callbacks.OnStop = app.OnStopEditField.Value;
            
            app.OnClosedHandle(app.Callbacks);
            close(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function on_cancel(app, event)
            app.OnClosedHandle([]);
            close(app.UIFigure);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @on_select_var, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @on_cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.OnModelSaveEditFieldLabel = uilabel(app.GridLayout3);
            app.OnModelSaveEditFieldLabel.Text = 'On Model Save';
            app.OnModelLoadEditFieldLabel = uilabel(app.GridLayout3);
            app.OnModelLoadEditFieldLabel.Text = 'On Model Load';
            app.OnModelLoadEditField = uieditfield(app.GridLayout3, 'text');
            app.OnModelSaveEditField = uieditfield(app.GridLayout3, 'text');
            app.OnStartEditFieldLabel = uilabel(app.GridLayout3);
            app.OnStartEditFieldLabel.Text = 'On Start';
            app.OnStartEditField = uieditfield(app.GridLayout3, 'text');
            app.OnStopEditFieldLabel = uilabel(app.GridLayout3);
            app.OnStopEditFieldLabel.Text = 'On Stop';
            app.OnStopEditField = uieditfield(app.GridLayout3, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = edit_callbacks_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end