classdef env_manager_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnvironmentmanagerUIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout11                   matlab.ui.container.GridLayout
        EnvTree                        matlab.ui.container.Tree
        EnvironmentNode                matlab.ui.container.TreeNode
        SystemNode                     matlab.ui.container.TreeNode
        ExperimentsNode                matlab.ui.container.TreeNode
        MetricsNode                    matlab.ui.container.TreeNode
        ControllersNode                matlab.ui.container.TreeNode
        Name                           matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_5                        matlab.ui.container.Panel
        HideTabsLabel                  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        EnvTab                         matlab.ui.container.Tab
        EditcallbacksButton            matlab.ui.control.Button
        Panel_21                       matlab.ui.container.Panel
        GridLayout11_2                 matlab.ui.container.GridLayout
        GeneratoroptionsButton         matlab.ui.control.Button
        SavechangesButton_2            matlab.ui.control.Button
        GenerateenvironmentButton      matlab.ui.control.Button
        ExportenvironmentButton        matlab.ui.control.Button
        CoderExtrinsicCheckBox         matlab.ui.control.CheckBox
        SteptimeEditField              matlab.ui.control.NumericEditField
        SteptimeEditFieldLabel         matlab.ui.control.Label
        EnvironmentNameEditField       matlab.ui.control.EditField
        EnvironmentNameEditFieldLabel  matlab.ui.control.Label
        EnvControlTab                  matlab.ui.container.Tab
        EditreferencesButton           matlab.ui.control.Button
        Panel_13                       matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        AddmetricButton                matlab.ui.control.Button
        AddexperimentButton            matlab.ui.control.Button
        AddcontrollerButton_2          matlab.ui.control.Button
        Panel_14                       matlab.ui.container.Panel
        GridLayout4_6                  matlab.ui.container.GridLayout
        EditsystemButton               matlab.ui.control.Button
        SysNameEnv                     matlab.ui.control.Label
        SystemLabel_3                  matlab.ui.control.Label
        SystemTab                      matlab.ui.container.Tab
        EditcallbacksButton_2          matlab.ui.control.Button
        Panel_22                       matlab.ui.container.Panel
        GridLayout11_3                 matlab.ui.container.GridLayout
        ImportsystemButton             matlab.ui.control.Button
        ExportsystemButton             matlab.ui.control.Button
        AdddisturbanceButtonSys        matlab.ui.control.Button
        SavechangesButton              matlab.ui.control.Button
        SysParamsStructName            matlab.ui.control.EditField
        SystemNameLabel_2              matlab.ui.control.Label
        Panel_7                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SelectsystemButton             matlab.ui.control.Button
        SysLibLabel                    matlab.ui.control.Label
        LibLabel                       matlab.ui.control.Label
        SysTypeLabel                   matlab.ui.control.Label
        SystemLabel_2                  matlab.ui.control.Label
        SysNameSystem                  matlab.ui.control.EditField
        SystemNameLabel                matlab.ui.control.Label
        ControllerTab                  matlab.ui.container.Tab
        EditcallbacksButton_3          matlab.ui.control.Button
        Panel_23                       matlab.ui.container.Panel
        GridLayout11_4                 matlab.ui.container.GridLayout
        ImportcontrollerButton         matlab.ui.control.Button
        ExportcontrollerButton         matlab.ui.control.Button
        AdddisturbanceButton           matlab.ui.control.Button
        AddestimatorButton             matlab.ui.control.Button
        CtlSavechangesButton           matlab.ui.control.Button
        DuplicatecontrollerButton      matlab.ui.control.Button
        RemovecontrollerButton         matlab.ui.control.Button
        CtlMuxPanel                    matlab.ui.container.Panel
        CtlMuxOutputs                  matlab.ui.control.EditField
        SystemNameLabel_10             matlab.ui.control.Label
        CtlRefHorizon                  matlab.ui.control.NumericEditField
        RefhorizonEditFieldLabel       matlab.ui.control.Label
        CtlMuxInputs                   matlab.ui.control.EditField
        SystemNameLabel_9              matlab.ui.control.Label
        AddcomponentButton             matlab.ui.control.Button
        IscomposableCheckBox           matlab.ui.control.CheckBox
        CtlParamsStructName            matlab.ui.control.EditField
        SystemNameLabel_3              matlab.ui.control.Label
        CtlName                        matlab.ui.control.EditField
        ControllerNameLabel            matlab.ui.control.Label
        CtlPluginPanel                 matlab.ui.container.Panel
        GridLayout4_2                  matlab.ui.container.GridLayout
        SelectconrollerButton          matlab.ui.control.Button
        CtlLibLabel                    matlab.ui.control.Label
        LibLabel_2                     matlab.ui.control.Label
        CtlTypeLabel                   matlab.ui.control.Label
        ControllerLabel                matlab.ui.control.Label
        EstimatorTab                   matlab.ui.container.Tab
        EditcallbacksButton_4          matlab.ui.control.Button
        Panel_24                       matlab.ui.container.Panel
        GridLayout11_5                 matlab.ui.container.GridLayout
        ImportestimatorButton          matlab.ui.control.Button
        ExportestimatorButton          matlab.ui.control.Button
        EstParamsStructName            matlab.ui.control.EditField
        SystemNameLabel_4              matlab.ui.control.Label
        Panel_10                       matlab.ui.container.Panel
        GridLayout4_3                  matlab.ui.container.GridLayout
        SelectEstimatorButton          matlab.ui.control.Button
        EstLibLabel                    matlab.ui.control.Label
        LibLabel_3                     matlab.ui.control.Label
        EstTypeLabel                   matlab.ui.control.Label
        EstimatorLabel                 matlab.ui.control.Label
        EstName                        matlab.ui.control.EditField
        ControllerNameLabel_2          matlab.ui.control.Label
        DisturbanceTab                 matlab.ui.container.Tab
        EditcallbacksButton_5          matlab.ui.control.Button
        Panel_25                       matlab.ui.container.Panel
        GridLayout11_6                 matlab.ui.container.GridLayout
        ImportdisturbanceButton        matlab.ui.control.Button
        ExportdisturbanceButton        matlab.ui.control.Button
        DistParamsStructName           matlab.ui.control.EditField
        SystemNameLabel_5              matlab.ui.control.Label
        Panel_11                       matlab.ui.container.Panel
        GridLayout4_4                  matlab.ui.container.GridLayout
        SelectEstimatorButton_2        matlab.ui.control.Button
        DistLibLabel                   matlab.ui.control.Label
        LibLabel_4                     matlab.ui.control.Label
        DistTypeLabel                  matlab.ui.control.Label
        DisturbanceLabel               matlab.ui.control.Label
        DistName                       matlab.ui.control.EditField
        ControllerNameLabel_3          matlab.ui.control.Label
        SubcontrollerTab               matlab.ui.container.Tab
        EditcallbacksButton_6          matlab.ui.control.Button
        Panel_26                       matlab.ui.container.Panel
        GridLayout11_7                 matlab.ui.container.GridLayout
        DuplicatesubcontrollerButton   matlab.ui.control.Button
        RemovesubcontrollerButton      matlab.ui.control.Button
        SubCtlMuxPanel                 matlab.ui.container.Panel
        SubCtlMuxOutputs               matlab.ui.control.EditField
        SystemNameLabel_12             matlab.ui.control.Label
        SubCtlRefHorizon               matlab.ui.control.NumericEditField
        RefhorizonEditField_2Label     matlab.ui.control.Label
        SubCtlMuxInputs                matlab.ui.control.EditField
        SystemNameLabel_11             matlab.ui.control.Label
        SubCtlParamsStructName         matlab.ui.control.EditField
        SystemNameLabel_6              matlab.ui.control.Label
        SubCtlName                     matlab.ui.control.EditField
        ControllerNameLabel_4          matlab.ui.control.Label
        Panel_12                       matlab.ui.container.Panel
        GridLayout4_5                  matlab.ui.container.GridLayout
        SelectconrollerBtn             matlab.ui.control.Button
        SubCtlLibLabel                 matlab.ui.control.Label
        LibLabel_5                     matlab.ui.control.Label
        SubCtlTypeLabel                matlab.ui.control.Label
        ControllerLabel_2              matlab.ui.control.Label
        EditScenariosTab               matlab.ui.container.Tab
        Panel_27                       matlab.ui.container.Panel
        GridLayout11_8                 matlab.ui.container.GridLayout
        DuplicateexperimentButton      matlab.ui.control.Button
        ImportexperimentButton         matlab.ui.control.Button
        ExportexperimentButton         matlab.ui.control.Button
        RemoveexperimentButton         matlab.ui.control.Button
        CtlSavechangesButton_3         matlab.ui.control.Button
        ConsthorizonreferenceCheckBox  matlab.ui.control.CheckBox
        Panel_20                       matlab.ui.container.Panel
        GridLayout7_4                  matlab.ui.container.GridLayout
        ScenarioReference              matlab.ui.control.Label
        SelectedMetricsCallback_2      matlab.ui.control.Button
        ReferenceLabel                 matlab.ui.control.Label
        ScenarioIc                     matlab.ui.control.EditField
        ICEditFieldLabel               matlab.ui.control.Label
        ScenarioName                   matlab.ui.control.EditField
        NameEditField_4Label           matlab.ui.control.Label
        EditMetricsTab                 matlab.ui.container.Tab
        Panel_28                       matlab.ui.container.Panel
        GridLayout11_9                 matlab.ui.container.GridLayout
        DuplicatemetricButton          matlab.ui.control.Button
        ImportmetricButton             matlab.ui.control.Button
        ExportmetricButton             matlab.ui.control.Button
        RemovemetricButton             matlab.ui.control.Button
        CtlSavechangesButton_2         matlab.ui.control.Button
        Panel_17                       matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        MetricCallback                 matlab.ui.control.Label
        SelectedMetricsCallback        matlab.ui.control.Button
        CallbackLabel                  matlab.ui.control.Label
        MetricName                     matlab.ui.control.EditField
        NameEditField_3Label           matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        ParamsPanel                    matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        ParamEditPanel                 matlab.ui.container.Panel
        RefreshParamsButton            matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        AddfolderButton                matlab.ui.control.Button
        AddfileButton                  matlab.ui.control.Button
        LoadfromworkspaceButton        matlab.ui.control.Button
        SavetoworkspaceButton          matlab.ui.control.Button
        ParamTree                      matlab.ui.container.Tree
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        AllTabs % Description
        SystemSet
        EnvCfg
        ScenariosCfg
        MetricsCfg
        EnvPath
        EnvName
        SelectedController
        Controllers
        Subcontrollers
        Estimators
        Disturbances
        Scenarios
        Metrics
        SysDisturbance
        ControllerChanges
        ScenarioChanges
        MetricChanges
        SystemChanges
        EnvChanges
        NewMetrics
        Context
        GeneratorOptions
    end
    
    methods (Access = private)
        
        function results = load_env(app)
            cfg = load_env_data(app.EnvPath);
            app.EnvCfg = cfg;
            app.SteptimeEditField.Value = app.EnvCfg.Metadata.Ts;

            if ~isfield(cfg.Metadata, 'Extrinsic')
                app.EnvCfg.Metadata.Extrinsic = 0;
            end
            app.CoderExtrinsicCheckBox.Value = app.EnvCfg.Metadata.Extrinsic;
            
            sys = cfg.System;
            if is_valid_field(sys, 'Disturbance')
                app.load_disturbance(0, 0);
            end

            for i=1:length(cfg.Controllers)
                ctl = cfg.Controllers(i);
                app.Controllers(end+1) = uitreenode(app.ControllersNode, 'Text', ctl.Name);
                app.Subcontrollers{ctl.Id} = matlab.ui.container.TreeNode.empty;
                app.Estimators{ctl.Id} = matlab.ui.container.TreeNode.empty;
                app.Disturbances{ctl.Id} = matlab.ui.container.TreeNode.empty;
                app.ControllerChanges(end+1) = 0;
                    
                if ~ctl.IsComposable
                    app.EnvCfg.Controllers(i).Components = options_as_struct(ControllerComponent);  
                    
                else
                    data = app.component_node_data('Subcontroller', ctl.Id);
                    for j=1:length(ctl.Components)
                        app.Subcontrollers{ctl.Id}(end+1) = uitreenode( ...
                            app.Controllers(end), 'Text', ctl.Components(j).Name, 'NodeData', data);
                    end
                end
                if is_valid_field(ctl, 'Estimator')
                    app.load_estimator(i);
                end
                if is_valid_field(ctl, 'Disturbance')
                    app.load_disturbance(i);
                end
            end
            
            for i=1:length(cfg.Scenarios)
                app.Scenarios(end+1) = uitreenode(app.ExperimentsNode, 'Text', cfg.Scenarios(i).Name);
            end
            app.ScenarioChanges = zeros(length(cfg.Scenarios), 1);

            for i=1:length(cfg.Metrics)
                app.Metrics(end+1) = uitreenode(app.MetricsNode, 'Text', cfg.Metrics(i).Name);
            end
            app.MetricChanges = zeros(length(cfg.Metrics), 1);
            app.NewMetrics = strings(length(cfg.Metrics), 1);
            app.Context = dir(fullfile(app.EnvPath, app.EnvName));
            app.fill_context(app.Context);
            
            app.load_generator_options();
            if ~isempty(app.GeneratorOptions)
                app.GeneratoroptionsButton.Enable = "on";
            else
                app.GeneratoroptionsButton.Enable = "off";
            end
        end
        
        function app = update_system(app)
            if strempty(app.EnvCfg.System.Lib)
                app.SystemSet = 0;
            else
                app.SystemSet = 1;
            end

            if app.SystemSet == 1
                app.EditsystemButton.Text = 'Edit system';
                app.ExportenvironmentButton.Visible = 'on';
                app.SysNameEnv.Text = app.EnvCfg.System.Name;
                app.SysNameSystem.Value = app.EnvCfg.System.Name;
                app.SysTypeLabel.Text = app.EnvCfg.System.Type;
                app.SysLibLabel.Text = app.EnvCfg.System.Lib;
                if ~strempty(app.EnvCfg.System.ParamsStructName)
                    app.fill_parameters(app.EnvCfg.System.Params);
                    app.SysParamsStructName.Value = app.EnvCfg.System.ParamsStructName;
                else
                    app.clear_parameters();
                end 
            else
                app.EditsystemButton.Text = 'Add system';
                app.ExportenvironmentButton.Visible = 'off';
                app.SysNameEnv.Text = '-';
                app.SysTypeLabel.Text = '-';
                app.SysLibLabel.Text = '-';
            end
        end

        function app = update_controller(app, idx)
            data = app.EnvCfg.Controllers(idx);

            app.CtlLibLabel.Text = data.Lib;
            app.CtlTypeLabel.Text = data.Type;
            app.CtlName.Value = data.Name;
            app.Controllers(idx).Text = app.handle_component_name(app.Controllers(idx).Text, data.Name);

            if ~strempty(data.ParamsStructName)
                app.fill_parameters(data.Params);
                app.CtlParamsStructName.Value = data.ParamsStructName;
            else
                app.clear_parameters();
            end


            if data.IsComposable
                app.CtlParamsStructName.Visible = "off";
                app.CtlMuxPanel.Visible = "off";
                app.CtlPluginPanel.Visible = "off";
                app.AddcomponentButton.Visible = "on";
            else
                app.CtlParamsStructName.Visible = "on";
                app.CtlMuxPanel.Visible = "on";
                app.CtlPluginPanel.Visible = "on";
                app.AddcomponentButton.Visible = "off";
            end
            
            if ~isempty(data.Mux)
                app.CtlMuxInputs.Value = mat2str(data.Mux.Inputs);
                app.CtlMuxOutputs.Value = mat2str(data.Mux.Outputs);
            end
            app.CtlRefHorizon.Value = data.RefHorizon;
        end

        function app = update_subcontroller(app, ctl_idx, sub_idx)
            ctl_id = app.EnvCfg.Controllers(ctl_idx).Id;
            data = app.EnvCfg.Controllers(ctl_idx).Components(sub_idx);
            app.SubCtlLibLabel.Text = data.Lib;
            app.SubCtlTypeLabel.Text = data.Type;
            app.SubCtlName.Value = data.Name;
            app.Subcontrollers{data.Id}(sub_idx).Text = ...
                app.handle_component_name(app.Subcontrollers{ctl_id}(sub_idx).Text, data.Name);

            if ~strempty(data.ParamsStructName)
                app.fill_parameters(data.Params);
                app.SubCtlParamsStructName.Value = data.ParamsStructName;
            else
                app.clear_parameters();
            end 
            if ~isempty(data.Mux)
                app.SubCtlMuxInputs.Value = mat2str(data.Mux.Inputs);
                app.SubCtlMuxOutputs.Value = mat2str(data.Mux.Outputs);
            end
            app.SubCtlRefHorizon.Value = data.RefHorizon;

        end

        function app = update_estimator(app, ctl_idx)
            ctl_id = app.EnvCfg.Controllers(ctl_idx).Id;
            data = app.EnvCfg.Controllers(ctl_idx).Estimator;
            app.EstLibLabel.Text = data.Lib;
            app.EstTypeLabel.Text = data.Type;
            app.EstName.Value = data.Name;
            app.Estimators{ctl_id}.Text = app.handle_component_name( ...
                app.Estimators{ctl_id}.Text, data.Name);

            if ~strempty(data.ParamsStructName)
                app.fill_parameters(data.Params);
                app.EstParamsStructName.Value = data.ParamsStructName;
            else
                app.clear_parameters();
            end 
        end

        function app = update_disturbance(app, ctl_idx, is_ctl)

            if ~exist('is_ctl', 'var')
                is_ctl = 1;
            end

            if is_ctl
                ctl_id = app.EnvCfg.Controllers(ctl_idx).Id;
                data = app.EnvCfg.Controllers(ctl_idx).Disturbance;
                app.DistLibLabel.Text = data.Lib;
                app.DistTypeLabel.Text = data.Type;
                app.DistName.Value = data.Name;
                app.Disturbances{ctl_id}.Text = app.handle_component_name( ...
                    app.Disturbances{ctl_id}.Text, data.Name);
    
                if ~isempty(data.ParamsStructName)
                    app.fill_parameters(data.Params);
                    app.DistParamsStructName.Value = data.ParamsStructName;
                else
                    app.clear_parameters();
                end 
            else
                data = app.EnvCfg.System.Disturbance;
                app.DistLibLabel.Text = data.Lib;
                app.DistTypeLabel.Text = data.Type;
                app.DistName.Value = data.Name;
                app.SysDisturbance.Text = app.handle_component_name( ...
                    app.SysDisturbance.Text, data.Name);
    
                if ~isempty(data.ParamsStructName)
                    app.fill_parameters(data.Params);
                    app.DistParamsStructName.Value = data.ParamsStructName;
                else
                    app.clear_parameters();
                end 
            end
        end

        function app = update_scenarios(app, idx)

            data = app.EnvCfg.Scenarios(idx);
            app.ScenarioName.Value = string(data.Name);
            app.Scenarios(idx).Text = app.handle_component_name( ...
                app.Scenarios(idx).Text, app.ScenarioName.Value);

            app.ScenarioIc.Value = mat2str(data.Ic);
            
            app.ScenarioReference.Text = app.handle_component_name( ...
                app.ScenarioReference.Text, string(data.Reference));
            app.ConsthorizonreferenceCheckBox.Value = data.ConstHorizonReference;
            if is_valid_field(data, 'Params')
                app.fill_parameters(data.Params);
            else
                app.clear_parameters();
            end 
        end

        function app = update_metrics(app, idx)
            
            data = app.EnvCfg.Metrics(idx);
            app.MetricName.Value = string(data.Name);
            app.Metrics(idx).Text = app.handle_component_name( ...
                app.Metrics(idx).Text, app.MetricName.Value);

            if ~strempty(app.NewMetrics(idx))
                [~, name, ~] = fileparts(app.NewMetrics(idx));
                app.MetricCallback.Text = name;
            else
                app.MetricCallback.Text = string(app.EnvCfg.Metrics(idx).Callback);
            end

            if ~strempty(data.ParamsStructName)
                app.fill_parameters(data.Params);
            else
                app.clear_parameters();
            end 
        end


        function load_estimator(app, idx)
            ctl = app.EnvCfg.Controllers(idx);
            est = ctl.Estimator;
            app.AddestimatorButton.Text = 'Remove disturbance';
            data = app.component_node_data('Estimator', ctl.Id);
            app.Estimators{ctl.Id} = uitreenode(app.Controllers(idx), 'Text', est.Name, 'NodeData', data);
        end

        function load_disturbance(app, idx, is_ctl)
            if ~exist('is_ctl', 'var')
                is_ctl = 1;
            end

            if is_ctl
                ctl = app.EnvCfg.Controllers(idx);
                dist = ctl.Disturbance;
                app.AdddisturbanceButton.Text = 'Remove disturbance';
                data = app.component_node_data('Disturbance', ctl.Id);
                app.Disturbances{ctl.Id} = uitreenode(app.Controllers(idx), 'Text', dist.Name, 'NodeData', data);
            else
                app.AdddisturbanceButtonSys.Text = 'Remove disturbance';
                data = app.component_node_data('Disturbance', app.EnvCfg.System.Id);
                app.SysDisturbance = uitreenode(app.SystemNode, 'Text', app.EnvCfg.System.Disturbance.Name, 'NodeData', data);
            end
        end

        function init_gui(app)
            app.HideTabsLabel.Visible = 'on';
            app.SelectedController = -1;
            app.Controllers = matlab.ui.container.TreeNode.empty;
            app.ControllerChanges = [];
            app.SystemChanges = 0;
            app.EnvChanges = 0;
            app.Subcontrollers = dictionary;
            app.Estimators = dictionary;
            app.Disturbances = dictionary;
            app.Scenarios = matlab.ui.container.TreeNode.empty;
            app.Metrics = matlab.ui.container.TreeNode.empty;
            expand(app.EnvironmentNode);
            app.set_tooltips();

            app.EnvironmentNameEditField.Value = app.EnvName;
        end


        function clear_parameters(app)
            delete(app.ParamTree.Children);
        end

        function t = is_string_param_supported(app)
            t = app.TabGroup.SelectedTab == app.EditMetricsTab;            
        end


        function fill_parameters_(app, params, parent)
            
            function t = create_text(name, data)
                if isa(data, 'struct')
                    t = strcat(name);
                else
                    if isa(data, 'timeseries')
                        v = strcat('timeseries[', num2str(size(data.Data)), ']');
                    elseif isa(data, 'uint8')
                        v = ['ui8(', char(data), ')'];
                    elseif isnumeric(data)
                        v = mat2str(data);
                    elseif ischar(data) || isstring(data)
                        if app.is_string_param_supported()
                            v = data;
                        else
                            v = "(UNSUPPORTED) " + data;
                        end
                    else
                        v = formattedDisplayText(data);
                    end
                    t = strcat(name, ":    ",v);
                end
            end
            
            names = fieldnames(params);

            for i=1:length(names)
                name = names{i};
                p = uitreenode(parent, "Text", create_text(name, params.(name)), "NodeData", params.(name));

                if isa(params.(name), 'struct')
                    app.fill_parameters_(params.(name), p);
                end
            end

        end

        function fill_parameters(app, params)
            clear_parameters(app);

            if ~isempty(params)
                app.fill_parameters_(params, app.ParamTree);
            end
        end

        function fill_context(app, context)
            clear_parameters(app);
            
            for i=1:length(context)
                if strcmp(context(i).name, '.') || strcmp(context(i).name, '..')
                    continue
                end
                data = context(i);
                uitreenode(app.ParamTree, 'Text', context(i).name, 'NodeData', data);
            end
            
        end

        function idx = get_active_subcontroller(app)
            n = app.EnvTree.SelectedNodes(1);
            idx = find(app.Subcontrollers{n.NodeData.ParentId} == n);
        end

        function idx = get_active_scenario(app)
            n = app.EnvTree.SelectedNodes(1);
            idx = find(app.Scenarios == n);
        end

        function idx = get_active_metric(app)
            n = app.EnvTree.SelectedNodes(1);
            idx = find(app.Metrics == n);
        end

        function idx = get_active_controller(app)
            n = app.EnvTree.SelectedNodes(1);
            if app.is_subtype(n, '')
                idx = find(n.Parent.Parent.Children == n.Parent);
            else
                idx = find(app.ControllersNode.Children == n);
            end
        end
        
        function results = handle_is_composable(app, value)
            idx = app.get_active_controller();
            if value == 1
                
                app.EnvCfg.Controllers(idx).IsComposable = 1;
            else
                remove = 0;
                % if has components, and any component has data.
                % If length is > 1, assume that component is set.
                if ~isempty(app.EnvCfg.Controllers(idx).Components) ...
                    && (length(app.EnvCfg.Controllers(idx).Components) > 1 ...
                        || ~isempty(app.EnvCfg.Controllers(idx).Components.Name))

                    msg = uiconfirm(app.EnvironmentmanagerUIFigure, ...
                        "Are you sure you want to remove all controller components?", ...
                        'Remove components', 'Options', {'Cancel', 'Yes'});
                    if strcmp(msg, "Yes")
                        remove = 1;  
                    else
                        app.IscomposableCheckBox.Value = 1;
                        return
                    end
                end
                app.EnvCfg.Controllers(idx).IsComposable = 0;
            
                if remove == 1
                    app.remove_all_subcontrollers(idx);  
                end
            end
        end

        function t = is_subtype(app, node, subtype)
            t = 0;    

            if isfield(node.NodeData, 'Type')
                if strcmp(node.NodeData.Type, subtype) || strempty(subtype)
                    t = 1;
                end
            end
        end
        
        function t = name_exists(app, name)
            t = 0;
        end

        function name = make_name_unique(app, name)
            % TODO
            if ~app.name_exists(name)
                return
            end
            i = 1;
            while 1
                name = strcat(name, num2str(i));
                if ~name_exists(name)
                    break
                end
            end
        end

        function on_controller_select(app)
            idx = app.get_active_controller();
            ctl = app.EnvCfg.Controllers(idx);
            app.IscomposableCheckBox.Value = ctl.IsComposable;
            app.handle_is_composable(ctl.IsComposable);
            app.TabGroup.SelectedTab = app.ControllerTab;
            app.update_controller(idx);
        end

        function on_subcontroller_select(app)
            ctl_idx = app.get_active_controller();
            sub_idx = app.get_active_subcontroller();
            app.TabGroup.SelectedTab = app.SubcontrollerTab;
            app.update_subcontroller(ctl_idx, sub_idx);
        end

        function on_estimator_select(app)
            ctl_idx = app.get_active_controller();
            app.TabGroup.SelectedTab = app.EstimatorTab;
            app.update_estimator(ctl_idx);
        end

        function on_disturbance_select(app)
            is_ctl = app.is_controller_distrurbance_selected();
            ctl_idx = app.get_active_controller();
            app.TabGroup.SelectedTab = app.DisturbanceTab;
            app.update_disturbance(ctl_idx, is_ctl);
        end

        function on_edit_scenarios_select(app)
            idx = get_active_scenario(app);
            app.TabGroup.SelectedTab = app.EditScenariosTab;
            app.update_scenarios(idx);
        end

        function on_edit_metrics_select(app)
            idx = get_active_metric(app);
            app.TabGroup.SelectedTab = app.EditMetricsTab;
            app.update_metrics(idx);
        end

        function remove_controller(app, controller)
            idx = app.get_active_controller();
            id = app.EnvCfg.Controllers(idx).Id;
            app.EnvCfg.Controllers(idx) = [];
            app.Controllers(idx) = [];
            controller.Parent = [];
            app.ControllerChanges(idx) = [];
            app.TabGroup.SelectedTab = app.EnvControlTab;
            remove_env_controller(app.EnvPath, id);
        end
              
        function remove_subcontroller(app, node)
            ctl_idx = app.get_active_controller();
            id = app.EnvCfg.Controllers(ctl_idx).Id;
            sub_idx = app.get_active_subcontroller();
            app.EnvCfg.Controllers(ctl_idx).Components(sub_idx) = [];
            app.Subcontrollers{id}(sub_idx) = []; 
            node.Parent = [];
            app.record_controller_change(idx);
            app.TabGroup.SelectedTab = app.ControllerTab;
        end

        function remove_estimator(app, node)
            ctl_idx = app.get_active_controller();
            id = app.EnvCfg.Controllers(ctl_idx).Id;
            app.Estimators{id} = matlab.ui.container.TreeNode.empty;
            app.EnvCfg.Controllers(ctl_idx).Estimator = [];
            node.Parent = [];
            app.record_controller_change(idx);
            app.TabGroup.SelectedTab = app.ControllerTab;
        end

        function remove_disturbance(app, node, is_ctl)
            
            if ~exist("is_ctl", "var")
                is_ctl = 1;
            end
            
            if is_ctl
                ctl_idx = app.get_active_controller();
                id = app.EnvCfg.Controllers(ctl_idx).Id;
                app.Disturbances{id} = matlab.ui.container.TreeNode.empty;
                app.EnvCfg.Controllers(ctl_idx).Disturbance = [];
                node.Parent = [];
                app.record_controller_change(idx);
                app.TabGroup.SelectedTab = app.ControllerTab;
            else
                app.SysDisturbance = matlab.ui.container.TreeNode.empty;
                app.EnvCfg.System.Disturbance = [];
                node.Parent = [];
                app.record_system_change();
                app.TabGroup.SelectedTab = app.SystemTab;
            end
        end

        function remove_all_subcontrollers(app, idx)
            id = app.EnvCfg.Controllers(idx).Id;
            app.EnvCfg.Controllers(idx).Components = options_as_struct(ControllerComponent);
            for i=1:length(app.Subcontrollers{id})
                app.Subcontrollers{id}(i).Parent = [];
            end
            app.Subcontrollers{id} = matlab.ui.container.TreeNode.empty;
            app.record_controller_change(idx);
        end


        function remove_metric(app, node)
            idx = app.get_active_metric();
            name = app.EnvCfg.Metrics(idx).Name;
            if ~strempty(app.EnvCfg.Metrics(idx).Callback)
                delete(fullfile(app.EnvPath, 'autogen', 'metrics', 'private', ...
                    strcat(app.EnvCfg.Metrics(idx).Callback, '.m')));
            end
            app.EnvCfg.Metrics(idx) = [];
            app.Metrics(idx) = [];
            app.NewMetrics(idx) = [];

            node.Parent = [];
            app.TabGroup.SelectedTab = app.EnvControlTab;
            app.MetricChanges(idx) = [];

            remove_env_metric(app.EnvPath, name);
        end

        function remove_scenario(app, node)
            idx = app.get_active_scenario();
            name = app.EnvCfg.Scenarios(idx).Name;
            app.EnvCfg.Scenarios(idx) = [];
            app.Scenarios(idx) = [];
            node.Parent = [];
            app.TabGroup.SelectedTab = app.EnvControlTab;
            app.ScenarioChanges(idx) = [];
            remove_env_scenario(app.EnvPath, name);

        end

        function on_system_selector_closed(app, selection)
            if isempty(selection)
                return
            end

            app.EnvCfg.System.Type = selection.Name;
            app.EnvCfg.System.Lib = selection.Lib;
            app.EnvCfg.System.LibVersion = selection.LibVersion;
                

            app.SysNameEnv.Text = app.make_name_unique(selection.Name);
            app.SysNameSystem.Value = app.SysNameEnv.Text;
            app.on_system_name_change();
            sys = app.EnvCfg.System;


            app.save_backup_params(sys);
            app.EnvCfg.System.ParamsStructName = strcat(sys.Name, '_params');
            app.SysParamsStructName.Value = app.EnvCfg.System.ParamsStructName;
            app.EnvCfg.System.Params = ...
                make_plugin_parameters(selection.Type, sys.Type, sys.Lib);
            
            app.update_system();
            app.record_system_change();

        end


        function on_controller_selector_closed(app, selection)
            if isempty(selection)
                return
            end
            idx = app.get_active_controller();
            app.EnvCfg.Controllers(idx).Lib = selection.Lib;
            app.EnvCfg.Controllers(idx).Type = selection.Name;
            app.EnvCfg.Controllers(idx).LibVersion = selection.LibVersion;

            app.EnvCfg.Controllers(idx).Name = app.make_name_unique(selection.Name);
            ctl = app.EnvCfg.Controllers(idx);
            
            app.save_backup_params(ctl);
            app.EnvCfg.Controllers(idx).ParamsStructName = strcat(ctl.Name, '_params');
            app.CtlParamsStructName.Value = app.EnvCfg.Controllers(idx).ParamsStructName;
            app.EnvCfg.Controllers(idx).Params = ...
                make_plugin_parameters(selection.Type, ctl.Type, ctl.Lib);
            
            app.update_controller(idx);
            app.record_controller_change(idx);
        end
        
        function on_subcontroller_selector_closed(app, selection)
            if isempty(selection)
                return
            end
            ctl_idx = app.get_active_controller();
            ctl = app.EnvCfg.Controllers(ctl_idx);
            sub_idx = app.get_active_subcontroller();
            sub_ctl = ctl.Components(sub_idx);
            app.EnvCfg.Controllers(ctl_idx).Components(sub_idx).Lib = selection.Lib;
            app.EnvCfg.Controllers(ctl_idx).Components(sub_idx).Type = selection.Name;
            app.EnvCfg.Controllers(ctl_idx).Components(sub_idx).LibVersion = selection.LibVersion;
            app.EnvCfg.Controllers(ctl_idx).Components(sub_idx).Name = app.make_name_unique(selection.Name);
            
            app.save_backup_params(sub_ctl);
            
            app.EnvCfg.Controllers(ctl_idx).Components(sub_idx).ParamsStructName = strcat(sub_ctl.Name, '_params');
            sub_ctl = app.EnvCfg.Controllers(ctl_idx).Components(sub_idx);
            app.SubCtlParamsStructName.Value = sub_ctl.ParamsStructName;
            app.EnvCfg.Controllers(ctl_idx).Components(sub_idx).Params = ...
                make_plugin_parameters(selection.Type, sub_ctl.Type, sub_ctl.Lib);

            app.update_subcontroller(ctl_idx, sub_idx);
            app.record_controller_change(ctl_idx);
        end

        function on_estimator_selector_closed(app, selection)
            if isempty(selection)
                return
            end
            ctl_idx = app.get_active_controller();

            app.EnvCfg.Controllers(ctl_idx).Estimator.Lib = selection.Lib;
            app.EnvCfg.Controllers(ctl_idx).Estimator.Type = selection.Name;
            app.EnvCfg.Controllers(ctl_idx).Estimator.LibVersion = selection.LibVersion;

            app.EnvCfg.Controllers(ctl_idx).Estimator.Name = app.make_name_unique(selection.Name);            
        
            app.save_backup_params(app.EnvCfg.Controllers(ctl_idx).Estimator);
            app.EnvCfg.Controllers(ctl_idx).Estimator.ParamsStructName = ...
                strcat(app.EnvCfg.Controllers(ctl_idx).Estimator.Name, '_params');

            est = app.EnvCfg.Controllers(ctl_idx).Estimator;
            app.SubCtlParamsStructName.Value = est.ParamsStructName;
            app.EnvCfg.Controllers(ctl_idx).Estimator.Params = ...
                make_plugin_parameters(selection.Type, est.Type, est.Lib);
            
            app.update_estimator(ctl_idx);
            app.record_controller_change(ctl_idx);
        end

        function on_disturbance_selector_closed(app, selection, is_ctl)
            if isempty(selection)
                return
            end
            
            if ~exist('is_ctl', 'var')
                is_ctl = 1;
            end
            
            if is_ctl
                ctl_idx = app.get_active_controller();
                
                app.EnvCfg.Controllers(ctl_idx).Disturbance.Lib = selection.Lib;
                app.EnvCfg.Controllers(ctl_idx).Disturbance.Type = selection.Name;
                app.EnvCfg.Controllers(ctl_idx).Disturbance.LibVersion = selection.LibVersion;
    
                app.EnvCfg.Controllers(ctl_idx).Disturbance.Name  = app.make_name_unique(selection.Name);
    
                app.save_backup_params(app.EnvCfg.Controllers(ctl_idx).Disturbance);
                app.EnvCfg.Controllers(ctl_idx).Disturbance.ParamsStructName = ...
                    strcat(app.EnvCfg.Controllers(ctl_idx).Disturbance.Name, '_params');
    
                dist = app.EnvCfg.Controllers(ctl_idx).Disturbance;
                app.DistParamsStructName.Value = dist.ParamsStructName;
                app.EnvCfg.Controllers(ctl_idx).Disturbance.Params = ...
                    make_plugin_parameters(selection.Type, dist.Type, dist.Lib);
                
                
                app.update_disturbance(ctl_idx);
                app.record_controller_change(ctl_idx);
            else
                app.EnvCfg.System.Disturbance.Lib = selection.Lib;
                app.EnvCfg.System.Disturbance.Type = selection.Name;
                app.EnvCfg.System.Disturbance.LibVersion = selection.LibVersion;
    
                app.DistName.Value = app.make_name_unique(selection.Name);
    
                app.save_backup_params(app.EnvCfg.System.Disturbance);
                app.EnvCfg.System.Disturbance.ParamsStructName = ...
                    strcat(app.EnvCfg.System.Disturbance.Name, '_params');
    
                dist = app.EnvCfg.System.Disturbance;
                app.DistParamsStructName.Value = dist.ParamsStructName;
                app.EnvCfg.System.Disturbance.Params = ...
                    make_plugin_parameters(selection.Type, dist.Type, dist.Lib);
                
                
                app.update_disturbance(0, 0);
                app.record_system_change();
            end
        end

        function on_reference_selector_closed(app, selected_ref)
            idx = app.get_active_scenario();
            app.EnvCfg.Scenarios(idx).Reference = selected_ref.Name;
            app.ScenarioReference.Text = selected_ref.Name;
            app.EnvCfg.Scenarios(idx).Params.RefParams = selected_ref.Params;
            app.record_scenario_change(idx);
            app.update_scenarios(idx);
        end

        function record_controller_change(app, idx)
            if idx == length(app.ControllerChanges) +1
                app.ControllerChanges(end+1) = 0;
            end
            if ~app.ControllerChanges(idx) 
                app.Controllers(idx).Text = strcat(app.Controllers(idx).Text, "*");
            end
            app.ControllerChanges(idx) = 1;
            %TODO
        end

        function record_system_change(app)
            if ~app.SystemChanges
                app.SystemNode.Text = strcat(app.SystemNode.Text, "*");
            end
            app.SystemChanges = 1;    
        end

        function record_env_change(app)
            if ~app.EnvChanges
                app.EnvironmentNode.Text = strcat(app.EnvironmentNode.Text, "*");
            end
            app.EnvChanges = 1;    
        end

        function record_scenario_change(app, idx)
            if idx == length(app.ScenarioChanges) +1
                app.ScenarioChanges(end+1) = 0;
            end
            if ~app.ScenarioChanges(idx)
                app.Scenarios(idx).Text = strcat(app.Scenarios(idx).Text, "*");
            end
            app.ScenarioChanges(idx) = 1;    
        end

        function record_metric_change(app, idx)
            if idx == length(app.MetricChanges) +1
                app.MetricChanges(end+1) = 0;
            end
            if ~app.MetricChanges(idx)
                app.Metrics(idx).Text = strcat(app.Metrics(idx).Text, "*");
            end
            app.MetricChanges(idx) = 1;    
        end

        function d = unixtime_now(app)
            d = posixtime(datetime('now'));
        end
        
        function data = component_node_data(app, type, parent_id)
            data.Type = type;
            data.ParentId = parent_id;
        end

        function save_params_to_workspace(app, data)
            if exist(data.ParamsStructName, 'var') == 1
                msg = uiconfirm(app.EnvironmentmanagerUIFigure, ...
                    strcat("Variable ", data.ParamsStructName, " already exists in base workspace." + ...
                    " Do you want to override it?"), 'Override variable', 'Options', {"Yes", "Cancel"});

                if ~strcmp(msg, 'Yes')
                    return
                end
            end
            % if params struct is empty, save it as 0, as
            % empty struct is not a valid simulink type
            if isstruct(data.Params) && isempty(fieldnames(data.Params)) ...
                    || isempty(data.Params)
                params = 0;
            else
                params = data.Params;
            end
            if ~strempty(data.ParamsStructName)
                assignin('base', data.ParamsStructName, params);
            else
                name = strcat(replace(data.Name, ' ', '_'), '_params');
                assignin('base', name, params);
                uiconfirm(app.EnvironmentmanagerUIFigure, ...
                    strcat("Parameters saved as '", name, "'."), 'Parameters saved', 'Options', "Ok");
            end
        end

        function save_backup_params(app, comp)
            if isempty(comp.Params)
                return
            end
            name = strcat(replace(comp.ParamsStructName, ' ', '_'), "_bkp");
            assignin('base', name, comp.Params);
            uiconfirm(app.EnvironmentmanagerUIFigure, ...
                strcat("Old parameters backup saved as '", name, "'"), 'Param backup', 'Options', {'OK'})
        end

        function copy_metrics(app, path, metrics)
            new_dir = fullfile(path, 'autogen', 'metrics', 'private');

            for i=1:length(metrics)
                f = fullfile(app.EnvPath, 'autogen', 'metrics', 'private', ...
                        strcat(metrics(i).Callback, '.m'));
                copyfile(f, new_dir)
            end
        end

        function display_param_buttons(app, type)
            if type == 1
                app.AddfileButton.Visible = 0;
                app.AddfolderButton.Visible = 0;
                app.DeleteButton.Visible = 0;
                app.LoadfromworkspaceButton.Visible = 1;
                app.SavetoworkspaceButton.Visible = 1;
                app.ParamsPanel.Title = 'Params';
            elseif type == 2
                app.AddfileButton.Visible = 1;
                app.AddfolderButton.Visible = 1;
                app.DeleteButton.Visible = 1;
                app.LoadfromworkspaceButton.Visible = 0;
                app.SavetoworkspaceButton.Visible = 0;
                app.ParamsPanel.Title = 'Context';
            else
                app.AddfileButton.Visible = 0;
                app.AddfolderButton.Visible = 0;
                app.DeleteButton.Visible = 0;
                app.LoadfromworkspaceButton.Visible = 0;
                app.SavetoworkspaceButton.Visible = 0;
                app.ParamsPanel.Title = '';
            end
        end

        function add_to_context(app, path)
            dest = fullfile(app.EnvPath, app.EnvName);

            if isfolder(path)
                [folder, name, ~] = fileparts(path);
                try 
                    mkdir(fullfile(path, name));
                catch 
                end
                dest = fullfile(dest, name);
            end
            
            copyfile(path, dest, "f")
            app.Context = dir(fullfile(app.EnvPath, app.EnvName));
            app.fill_context(app.Context);
        end
    

        function on_remove_selection_from_context(app, nodes)
            for i=1:length(nodes)
                n = nodes(i);
                full_path = fullfile(n.NodeData.folder, n.NodeData.name);
                if isfolder(full_path)
                    rmdir(full_path, "s");
                else
                    delete(full_path);
                end
                idx = cellfun(@(x) strcmp(n.NodeData.name, x), {app.Context.name});
                app.Context(idx) = [];

            end
            
            delete(nodes);
        end


        function is_ctl = is_controller_distrurbance_selected(app)
            s = app.EnvTree.SelectedNodes.Parent;
            p = s.Parent;

            if startsWith(s.Text, 'System') && p == app.EnvironmentNode
                is_ctl = 0;
            else
                is_ctl = 1;
            end
        end


        function new_name = handle_component_name(app, old_name, new_name)
            if endsWith(old_name, '*')
                new_name = strcat(new_name, '*');
            end
        end

        function set_tooltips(app)
            app.CoderExtrinsicCheckBox.Tooltip = ['Check this if you want Matlab to interpred all the scripts.' ...
                ' It is usefull for debugging and fast prototyping of the plugin implementations because' ...
                ' simulink coder will ignore them.' ...
                ' Uncheck when component implementation is finished to achieve better runtime performance.'];

            app.SteptimeEditFieldLabel.Tooltip = 'Simulation step time';
            app.EnvironmentNameEditFieldLabel.Tooltip = 'Control environment name. Has to be a valid variable name';

        end


        function set_loaded_params(app, selection, p)
            idx = app.get_active_controller();
            
            if app.TabGroup.SelectedTab == app.ControllerTab
                app.EnvCfg.Controllers(idx).Params = p;
                app.CtlParamsStructName.Value = selection;
                app.on_controller_param_struct_change([]);
            elseif app.TabGroup.SelectedTab == app.SystemTab
                app.EnvCfg.System.Params = p;
                app.SysParamsStructName.Value = selection;
                app.on_system_param_struct_change([]);
            elseif app.TabGroup.SelectedTab == app.SubcontrollerTab
                sub_idx = app.get_active_subcontroller();
                app.EnvCfg.Controllers(idx).Components(sub_idx).Params = p;
                app.SubCtlParamsStructName.Value = selection;
                app.on_subcontroller_param_struct_change([]);
            elseif app.TabGroup.SelectedTab == app.EstimatorTab
                app.EnvCfg.Controllers(idx).Estimator.Params = p;
                app.EstParamsStructName.Value = selection;
                app.on_estimator_param_struct_name_change([]);
            elseif app.TabGroup.SelectedTab == app.DisturbanceTab
                is_ctl = app.is_controller_distrurbance_selected();
                if is_ctl
                    app.EnvCfg.Controllers(idx).Disturbance.Params = p;
                else
                    app.EnvCfg.System.Disturbance.Params = p;
                end
                app.DistParamsStructName.Value = selection;
                app.on_disturbance_param_struct_name_change([]);
            elseif app.TabGroup.SelectedTab == app.EditMetricsTab
                idx = app.get_active_metric();
                if strempty(app.EnvCfg.Metrics(idx).Callback) && ...
                        strempty(app.NewMetrics(idx))
                    error('Cannot load parameters if callback is not selected');
                end
                app.EnvCfg.Metrics(idx).Params = p;
                app.record_metric_change(idx);
            elseif app.TabGroup.SelectedTab == app.EditScenariosTab
                idx = app.get_active_scenario();
                app.EnvCfg.Scenarios(idx).Params = p;
                app.record_scenario_change(idx);
            else
                error('Cannot load parameters. Tab error...');
            end
            app.fill_parameters(p);
        end

        function load_generator_options(app)
            app.GeneratorOptions = load_env_generator_options(app.EnvPath);
        end
       
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name, env_path)
            app.AllTabs = [app.SystemTab, app.ControllerTab];
            app.Name.Text = name;
            app.EnvPath = env_path;
            app.EnvName = name;
            app.init_gui();
            app.load_env();

        end

        % Selection change function: TabGroup
        function on_selection_changed(app, event)
            
            if strcmp(app.TabGroup.SelectedTab.Title, 'Env')
                return
            end
            app.TabGroup.SelectedTab = event.OldValue;
        end

        % Selection changed function: EnvTree
        function on_selected_node(app, event)
            selectedNodes = app.EnvTree.SelectedNodes;
            app.clear_parameters();

            if isempty(selectedNodes)
                app.TabGroup.Visible = "off";
                return
            end
            if length(selectedNodes) > 1
                app.TabGroup.Visible = "off";
                return
            end
            app.TabGroup.Visible = "on";
            node = selectedNodes(1);
            expand(node);
            if startsWith(node.Text, "Environment")
                app.TabGroup.SelectedTab = app.EnvTab;
                app.display_param_buttons(2);
                app.fill_context(app.Context);
                return
            end

            if startsWith(node.Text, "System") && startsWith(node.Parent.Text, "Environment")
                app.TabGroup.SelectedTab = app.SystemTab;
                app.update_system();
                app.display_param_buttons(1);
                return
            end
            if startsWith(node.Text, "Controllers") && startsWith(node.Parent.Text, "Environment")
                app.TabGroup.SelectedTab = app.EnvControlTab;
                app.display_param_buttons(0);
                return
            end
            if startsWith(node.Text, "Experiments") && startsWith(node.Parent.Text, "Environment")
                app.TabGroup.SelectedTab = app.EnvControlTab;
                app.display_param_buttons(0);
                return
            end

            if startsWith(node.Text, "Metrics") && startsWith(node.Parent.Text, "Environment")
                app.TabGroup.SelectedTab = app.EnvControlTab;
                app.display_param_buttons(0);
                return
            end


            if strcmp(node.Parent.Text, "Experiments")
                app.on_edit_scenarios_select();
                app.display_param_buttons(1);
                return
            end

            if strcmp(node.Parent.Text, "Metrics")
                app.on_edit_metrics_select();
                app.display_param_buttons(1);
                return
            end


            if strcmp(node.Parent.Text, "Controllers")
                app.on_controller_select();
                app.display_param_buttons(1);
            end

            if app.is_subtype(node, 'Subcontroller')
                app.on_subcontroller_select()
                app.display_param_buttons(1);
            end

            if app.is_subtype(node, 'Estimator')
                app.on_estimator_select()
                app.display_param_buttons(1);
            end

            if app.is_subtype(node, 'Disturbance')
                app.on_disturbance_select()
                app.display_param_buttons(1);
            end

          
        end

        % Button pushed function: EditsystemButton
        function on_edit_system_click(app, event)
            app.TabGroup.SelectedTab = app.SystemTab;
        end

        % Button pushed function: SelectsystemButton
        function on_select_system_btn(app, event)
            plugins = get_available_plugins(parse_comp_type('sys'));
            selector_app(plugins, @app.on_system_selector_closed, @(x) string(x.Name));
        end

        % Value changed function: SysNameSystem
        function on_system_name_change(app, event)
            value = app.SysNameSystem.Value;
            app.EnvCfg.System.Name = value;
            app.record_system_change();
        end

        % Button pushed function: SavechangesButton
        function on_system_save_changes(app, event)
            save_env_system(app.EnvPath, app.EnvCfg.System);
            app.SystemChanges = 0;
            app.SystemNode.Text = replace(app.SystemNode.Text, '*', '');
        end

        % Button pushed function: AddcontrollerButton_2
        function on_add_controller(app, event)
            app.TabGroup.SelectedTab =  app.ControllerTab;
            
            app.Controllers(end+1) = uitreenode(app.ControllersNode, 'Text', 'New_controller');
            app.EnvTree.SelectedNodes = app.Controllers(end);
            
            
            if isempty(app.EnvCfg.Controllers)
                app.EnvCfg.Controllers = options_as_struct(ControllerOptions);
            else
                app.EnvCfg.Controllers(end+1) = options_as_struct(ControllerOptions);
            end
            ctl = app.EnvCfg.Controllers(end);
            app.ControllerChanges(end+1) = 0;

            app.EnvCfg.Controllers(end).Components = options_as_struct(ControllerComponent);
            app.Subcontrollers{ctl.Id} = matlab.ui.container.TreeNode.empty;
            app.Estimators{ctl.Id} = matlab.ui.container.TreeNode.empty;
            app.Disturbances{ctl.Id} = matlab.ui.container.TreeNode.empty;
            
            app.EnvCfg.Controllers(end).Name = 'New_controller';
            app.record_controller_change(length(app.EnvCfg.Controllers));
            app.on_selected_node(app.EnvTree.SelectedNodes); % not triggered automatically
            expand(app.ControllersNode);
        end

        % Button pushed function: CtlSavechangesButton
        function on_save_controller(app, event)
            idx = app.get_active_controller();
            
            save_env_controller(app.EnvPath, app.EnvCfg.Controllers(idx));
            app.ControllerChanges(idx) = 0;
            app.Controllers(idx).Text = replace(app.Controllers(idx).Text, '*', '');
        end

        % Value changed function: CtlName
        function on_controller_name_changed(app, event)
            value = app.CtlName.Value;
            idx = app.get_active_controller();
            app.EnvCfg.Controllers(idx).Name = value;
            app.Controllers(idx).Text = value;
            app.record_controller_change(idx);
        end

        % Button pushed function: SelectconrollerButton
        function on_select_controller_plugin(app, event)
            plugins = get_available_plugins(parse_comp_type('ctl'));
            selector_app(plugins, @app.on_controller_selector_closed, @(x) string(x.Name));

        end

        % Button pushed function: RemovecontrollerButton
        function on_remove_controller(app, event)
            app.remove_controller(app.EnvTree.SelectedNodes);
        end

        % Window key press function: EnvironmentmanagerUIFigure
        function on_key_press(app, event)
            
            nodes = app.EnvTree.SelectedNodes;
            key = event.Key;


            if strcmp(key, 'delete')
                if app.TabGroup.SelectedTab == app.EnvTab   
                    app.on_remove_selection_from_context(app.ParamTree.SelectedNodes);
                elseif startsWith(nodes.Parent.Text, "Controllers")
                    app.remove_controller(nodes);
                elseif app.is_subtype(nodes, 'Subcontroller')
                    app.remove_subcontroller(nodes);
                elseif app.is_subtype(nodes, 'Estimator')
                    app.remove_estimator(nodes);
                elseif app.is_subtype(nodes, 'Disturbance')
                    app.remove_disturbance(nodes)
                elseif startsWith(nodes.Parent.Text, 'Metrics')
                    app.remove_metric(nodes)
                elseif startsWith(nodes.Parent.Text, 'Experiments')
                    app.remove_scenario(nodes)
                end


            end
        end

        % Value changed function: CtlParamsStructName
        function on_controller_param_struct_change(app, event)
            value = app.CtlParamsStructName.Value;
            idx = app.get_active_controller();
            params_struct = app.EnvCfg.Controllers(idx).ParamsStructName;
            if exist(params_struct, 'var') == 1

                try
                    evalin("base", [value, ' = ', params_struct, '; clear(', params_struct, ');']);
                catch
                end
            end

            app.EnvCfg.Controllers(idx).ParamsStructName = value;
            app.record_controller_change(idx);

        end

        % Button pushed function: LoadfromworkspaceButton
        function on_load_params_from_workspace(app, event)
            function on_selection(selection)
                if isempty(selection)
                    return
                end
                p = evalin('base', selection);
                app.set_loaded_params(selection, p);
            end
            
            [vars, ~] = list_vars_from_workspace('');
            selector_app(vars, @on_selection)
        end

        % Button pushed function: SavetoworkspaceButton
        function on_save_params_to_workspace(app, event)
            idx = app.get_active_controller();

            if app.TabGroup.SelectedTab == app.ControllerTab
                data = app.EnvCfg.Controllers(idx);
            elseif app.TabGroup.SelectedTab == app.SystemTab
                data = app.EnvCfg.System;
            elseif app.TabGroup.SelectedTab == app.SubcontrollerTab
                sub_idx = app.get_active_subcontroller();
                data = app.EnvCfg.Controllers(idx).Components(sub_idx);
            elseif app.TabGroup.SelectedTab == app.EstimatorTab
                data = app.EnvCfg.Controllers(idx).Estimator;
            elseif app.TabGroup.SelectedTab == app.DisturbanceTab
                is_ctl = app.is_controller_distrurbance_selected();
                if is_ctl
                    data = app.EnvCfg.Controllers(idx).Disturbance;   
                else
                    data = app.EnvCfg.System.Disturbance;
                end
            elseif app.TabGroup.SelectedTab == app.EditMetricsTab
                idx = app.get_active_metric();
                data = app.EnvCfg.Metrics(idx);   
            elseif app.TabGroup.SelectedTab == app.EditScenariosTab
                idx = app.get_active_scenario();
                data = app.EnvCfg.Scenarios(idx);   
            else
                error('Cannot save parameters. Tab error...');
            end

            app.save_params_to_workspace(data);
        end

        % Selection changed function: ParamTree
        function on_param_select(app, event)
            selectedNodes = app.ParamTree.SelectedNodes;
            if isa(selectedNodes.NodeData, 'struct')
                return
            end
        end

        % Value changed function: SysParamsStructName
        function on_system_param_struct_change(app, event)
            value = app.SysParamsStructName.Value;
            app.EnvCfg.System.ParamsStructName = value;

            app.record_system_change();
        end

        % Window key release function: EnvironmentmanagerUIFigure
        function on_key_relese(app, event)
            key = event.Key;
            
        end

        % Button pushed function: DuplicatecontrollerButton
        function on_duplicate_controller(app, event)
            idx = app.get_active_controller();
            curr_ctl = options_as_struct(app.EnvCfg.Controllers(idx));
            curr_ctl.Id = new_uuid();
            t_now = replace(string(app.unixtime_now), '.', '');
            app.TabGroup.SelectedTab =  app.ControllerTab;
            new_name = strcat(curr_ctl.Name, "_", t_now);
            curr_ctl.Name = new_name;
            curr_ctl.ParamsStructName = strcat(curr_ctl.ParamsStructName, "_", t_now);
            app.Controllers(end+1) = uitreenode(app.ControllersNode, 'Text', new_name);
            app.EnvTree.SelectedNodes = app.Controllers(end);
           
            app.EnvCfg.Controllers(end+1) = curr_ctl;
            app.ControllerChanges(end+1) = 0;
            app.record_controller_change(length(app.EnvCfg.Controllers));
            app.on_selected_node(app.EnvTree.SelectedNodes); % not triggered automatically
            expand(app.ControllersNode);            

        end

        % Button pushed function: ExportenvironmentButton
        function on_export_env(app, event)
            path = create_comp_export_dir(fullfile('export', app.EnvCfg.Metadata.Name));
            save_env_data(path, app.EnvCfg, 0);
            app.copy_metrics(path, app.EnvCfg.Metrics);

            fclose(fopen(fullfile(path, strcat(app.EnvCfg.Metadata.Name, '.cse')), 'w'));
        end

        % Value changed function: IscomposableCheckBox
        function on_is_composable_change(app, event)
            value = app.IscomposableCheckBox.Value;
            app.handle_is_composable(value);
            idx = app.get_active_controller();
            app.update_controller(idx);
            app.record_controller_change(idx);
        end

        % Button pushed function: AddcomponentButton
        function on_add_controller_component(app, event)
            app.TabGroup.SelectedTab = app.SubcontrollerTab;
            idx = app.get_active_controller();
            ctl_gui = app.Controllers(idx);
            ctl = app.EnvCfg.Controllers(idx);
            data = app.component_node_data('Subcontroller', ctl.Id);
            app.Subcontrollers{ctl.Id}(end+1) = uitreenode(ctl_gui, 'Text', 'New_component', 'NodeData', data);
            app.EnvTree.SelectedNodes = app.Subcontrollers{ctl.Id}(end);  
            if length(app.EnvCfg.Controllers(idx).Components) > 1 ...
                    || ~strempty(app.EnvCfg.Controllers(idx).Components(1).Name)
                app.EnvCfg.Controllers(idx).Components(end+1) = options_as_struct(ControllerComponent);
            end
            app.EnvCfg.Controllers(idx).Components(end).Name = 'New_component';
            app.on_selected_node(app.EnvTree.SelectedNodes); % not triggered automatically
            expand(ctl_gui);
            app.record_controller_change(idx);
        end

        % Button pushed function: SelectconrollerBtn
        function on_select_subcontroller_plugin(app, event)
            plugins = get_available_plugins(parse_comp_type('ctl'));
            selector_app(plugins, @app.on_subcontroller_selector_closed, @(x) string(x.Name));

        end

        % Button pushed function: RemovesubcontrollerButton
        function on_remove_subcontroller(app, event)
            app.remove_subcontroller(app.EnvTree.SelectedNodes);
        end

        % Button pushed function: DuplicatesubcontrollerButton
        function on_duplicate_subcontroller(app, event)
            ctl_idx = app.get_active_controller();
            ctl = app.EnvCfg.Controllers(ctl_idx);
            sub_idx = app.get_active_subcontroller();
            sub_ctl = ctl.Components(sub_idx);
            t_now = string(app.unixtime_now());
            app.TabGroup.SelectedTab = app.SubcontrollerTab;
            new_name = strcat(sub_ctl.Name, "_", t_now);
            sub_ctl.Name = new_name;
            sub_ctl.ParamsStructName = strcat(sub_ctl.ParamsStructName, "_", t_now);
            data = app.component_node_data('Subcontroller', ctl.Id);
            app.Subcontrollers{ctl.Id}(end+1) = uitreenode(app.Controllers(ctl_idx), 'Text', new_name, 'NodeData', data);
            app.EnvTree.SelectedNodes = app.Subcontrollers{ctl.Id}(end);
           
            app.EnvCfg.Controllers(ctl_idx).Components(end+1) = options_as_struct(sub_ctl);            
            app.on_selected_node(app.EnvTree.SelectedNodes); % not triggered automatically
            expand(app.ControllersNode); 
            app.record_controller_change(ctl_idx);
        end

        % Value changed function: SubCtlParamsStructName
        function on_subcontroller_param_struct_change(app, event)
            value = app.SubCtlParamsStructName.Value;
            idx = app.get_active_controller();

            sub_idx = app.get_active_subcontroller();
            params_struct = app.EnvCfg.Controllers(idx).Components(sub_idx).ParamsStructName;
            if exist(params_struct, 'var') == 1

                try
                    evalin("base", [value, ' = ', params_struct, '; clear(', params_struct, ');']);
                catch
                end
            end

            app.EnvCfg.Controllers(idx).Components(sub_idx).ParamsStructName = value;
            app.record_controller_change(idx);
        end

        % Value changed function: EnvironmentNameEditField
        function on_env_name_change(app, event)
            value = app.EnvironmentNameEditField.Value;
            old_name = app.EnvName;
            app.EnvName = value;
            app.EnvCfg.Name = value;
            app.EnvPath = rename_environment(old_name, value);
        end

        % Button pushed function: AddestimatorButton
        function on_add_estimator(app, event)
            
            idx = app.get_active_controller();
            ctl = app.EnvCfg.Controllers(idx);
            if isempty(ctl.Estimator)
                app.AddestimatorButton.Text = 'Remove estimator';
                app.EnvCfg.Controllers(idx).Estimator = options_as_struct(EstimatorOptions);
                app.TabGroup.SelectedTab = app.EstimatorTab;
                data = app.component_node_data('Estimator', ctl.Id);
                app.Estimators{ctl.Id} = uitreenode(app.Controllers(idx), 'Text', 'New estimator', 'NodeData', data);
                app.EnvCfg.Controllers(idx).Estimator.Name = 'New estimator';
                app.EnvTree.SelectedNodes = app.Estimators{ctl.Id};
                app.update_estimator(idx);
            else
                app.remove_estimator(app.Estimators{ctl.Id});
                app.AddestimatorButton.Text = 'Add estimator';
            end
            app.record_controller_change(idx);

        end

        % Button pushed function: AdddisturbanceButton
        function on_add_disturbance(app, event)
            idx = app.get_active_controller();
            ctl = app.EnvCfg.Controllers(idx);
            if isempty(ctl.Disturbance)
                app.AdddisturbanceButton.Text = 'Remove disturbance';
                app.EnvCfg.Controllers(idx).Disturbance = options_as_struct(DisturbanceOptions);
                app.TabGroup.SelectedTab = app.DisturbanceTab;
                data = app.component_node_data('Disturbance', ctl.Id);
                app.Disturbances{ctl.Id} = uitreenode(app.Controllers(idx), 'Text', 'New disturbance', 'NodeData', data);
                app.EnvCfg.Controllers(idx).Disturbance.Name = 'New disturbance';
                app.EnvTree.SelectedNodes = app.Disturbances{ctl.Id};
                app.update_disturbance(idx);
            else
                app.remove_disturbance(app.Disturbances{ctl.Id});
                app.AdddisturbanceButton.Text = 'Add disturbance';
            end
            app.record_controller_change(idx);
        end

        % Value changed function: EstName
        function on_estimator_name_change(app, event)
            value = app.EstName.Value;
            idx = app.get_active_controller();
            ctl = app.EnvCfg.Controllers(idx);
            app.EnvCfg.Controllers(idx).Estimator.Name = value;
            app.Estimators{ctl.Id}.Text = value;
            app.record_controller_change(idx);
        end

        % Value changed function: DistName
        function on_disturbance_name_change(app, event)
            value = app.DistName.Value;
            is_ctl = is_controller_distrurbance_selected(app);
            
            if is_ctl
                idx = app.get_active_controller();
                ctl = app.EnvCfg.Controllers(idx);
                app.EnvCfg.Controllers(idx).Disturbance.Name = value;
                app.Disturbances{ctl.Id}.Text = value;
                app.record_controller_change(idx);
            else
                app.EnvCfg.System.Disturbance.Name = value;
                app.SysDisturbance.Text = value;
                app.record_system_change();
            end
        end

        % Value changed function: SubCtlName
        function on_subcontroller_name_change(app, event)
            value = app.SubCtlName.Value;
            idx = app.get_active_controller();
            ctl = app.EnvCfg.Controllers(idx);
            sub_idx = app.get_active_subcontroller();
            app.EnvCfg.Controllers(idx).Components(sub_idx).Name = value;
            app.Subcontrollers{ctl.Id}(sub_idx).Text = value;
            app.record_controller_change(idx);
        end

        % Value changed function: EstParamsStructName
        function on_estimator_param_struct_name_change(app, event)
            value = app.EstParamsStructName.Value;
            idx = app.get_active_controller();
            params_struct = app.EnvCfg.Controllers(idx).Estimator.ParamsStructName;
            if exist(params_struct, 'var') == 1
                try
                    evalin("base", [value, ' = ', params_struct, '; clear(', params_struct, ');']);
                catch
                end
            end
            app.EnvCfg.Controllers(idx).Estimator.ParamsStructName = value;
            app.record_controller_change(idx);
        end

        % Value changed function: DistParamsStructName
        function on_disturbance_param_struct_name_change(app, event)
            value = app.DistParamsStructName.Value;
            is_ctl = is_controller_distrurbance_selected(app);
            if is_ctl
                idx = app.get_active_controller();
                params_struct = app.EnvCfg.Controllers(idx).Disturbance.ParamsStructName;
                if exist(params_struct, 'var') == 1
                    try
                        evalin("base", [value, ' = ', params_struct, '; clear(', params_struct, ');']);
                    catch
                    end
                end
                app.EnvCfg.Controllers(idx).Disturbance.ParamsStructName = value;
                app.record_controller_change(idx);
            else
                params_struct = app.EnvCfg.System.Disturbance.ParamsStructName;
                if exist(params_struct, 'var') == 1
                    try
                        evalin("base", [value, ' = ', params_struct, '; clear(', params_struct, ');']);
                    catch
                    end
                end
                app.EnvCfg.System.Disturbance.ParamsStructName = value;
                app.record_system_change();
            end
        end

        % Button pushed function: SelectEstimatorButton
        function on_select_estimator(app, event)
            plugins = get_available_plugins(parse_comp_type('est'));
            selector_app(plugins, @app.on_estimator_selector_closed, @(x) string(x.Name));

        end

        % Button pushed function: SelectEstimatorButton_2
        function on_select_disturbance(app, event)
            plugins = get_available_plugins(parse_comp_type('dist'));
            is_ctl = is_controller_distrurbance_selected(app);

            function dist_selector_wrapper(x)
                app.on_disturbance_selector_closed(x, is_ctl);
            end

            selector_app(plugins, @dist_selector_wrapper, @(x) string(x.Name));
        end

        % Value changed function: CtlMuxOutputs
        function on_controller_mux_outputs_change(app, event)
            value = app.CtlMuxOutputs.Value;
            idx = app.get_active_controller();
            app.EnvCfg.Controllers(idx).Mux.Outputs = str2num(value);
            app.record_controller_change(idx);
        end

        % Value changed function: CtlMuxInputs
        function on_controller_mux_inputs_change(app, event)
            value = app.CtlMuxInputs.Value;
            idx = app.get_active_controller();
            app.EnvCfg.Controllers(idx).Mux.Inputs = str2num(value);
            app.record_controller_change(idx);
        end

        % Value changed function: CtlRefHorizon
        function on_controller_ref_horizon_change(app, event)
            value = app.CtlRefHorizon.Value;
            idx = app.get_active_controller();
            app.EnvCfg.Controllers(idx).RefHorizon = value;
            app.record_controller_change(idx);
        end

        % Value changed function: SubCtlMuxInputs
        function on_subcontroller_mux_inputs_change(app, event)
            value = app.SubCtlMuxInputs.Value;
            idx = app.get_active_controller();
            sub_idx = app.get_active_subcontroller();
            app.EnvCfg.Controllers(idx).Components(sub_idx).Mux.Inputs = str2num(value);
            app.record_controller_change(idx);
        end

        % Value changed function: SubCtlMuxOutputs
        function on_subcontroller_mux_outputs_change(app, event)
            value = app.SubCtlMuxOutputs.Value;
            idx = app.get_active_controller();
            sub_idx = app.get_active_subcontroller();
            app.EnvCfg.Controllers(idx).Components(sub_idx).Mux.Outputs = str2num(value);
            app.record_controller_change(idx);
        end

        % Value changed function: SubCtlRefHorizon
        function on_subcontroller_ref_horizon_change(app, event)
            value = app.SubCtlRefHorizon.Value;
            idx = app.get_active_controller();
            sub_idx = app.get_active_subcontroller();
            app.EnvCfg.Controllers(idx).Components(sub_idx).RefHorizon = value;
            app.record_controller_change(idx);
        end

        % Button pushed function: GenerateenvironmentButton
        function on_generate_env(app, event)
            
            if isempty(app.GeneratorOptions)
               app.on_generator_options([]);
               return
            end

            if isempty(app.GeneratorOptions)
                return
            end

            app.GeneratoroptionsButton.Enable = "on";
            
            if ~strempty(app.EnvCfg.System.ParamsStructName)
                app.save_params_to_workspace(app.EnvCfg.System);
            end
            if isfield(app.EnvCfg.System, 'Disturbance') ...
                &&  ~isempty(app.EnvCfg.System.Disturbance)
                app.save_params_to_workspace(app.EnvCfg.System.Disturbance);
            end
            indices = [];
            for si=1:length(app.GeneratorOptions.SelectedIds)
                id = app.GeneratorOptions.SelectedIds(si);
                
                mask = cellfun(@(x) strcmp(x, id), {app.EnvCfg.Controllers.Id});
                i = find(mask);
                indices = [indices, i];
                c = app.EnvCfg.Controllers(i);
                if c.IsComposable
                    for j=1:length(c.Components)
                        app.save_params_to_workspace(c.Components(j));
                    end
                else
                    app.save_params_to_workspace(c);
                end
                if ~isempty(c.Estimator)
                    app.save_params_to_workspace(c.Estimator);
                end
                if ~isempty(c.Disturbance)
                    app.save_params_to_workspace(c.Disturbance);
                end
            end
            app.EnvCfg.Metadata.Ts = double(app.SteptimeEditField.Value);
            generate_control_environment(app.EnvCfg, app.EnvPath, indices);
            
        end

        % Button pushed function: AddexperimentButton
        function on_add_scenario(app, event)
            app.TabGroup.SelectedTab =  app.EditScenariosTab;
            
            app.Scenarios(end+1) = uitreenode(app.ExperimentsNode, 'Text', 'New_scenario');
            app.EnvTree.SelectedNodes = app.Scenarios(end);
            
            id = string(java.util.UUID.randomUUID.toString);
            new_s = struct('Id', id, 'Name', 'New_scenario', 'Ic', [], ...
                    'Reference', '', 'Params', struct, ...
                    'ParamsStructName', '', 'ConstHorizonReference', 0);
            if isempty(app.EnvCfg.Scenarios)
                app.EnvCfg.Scenarios = new_s;
            else
                app.EnvCfg.Scenarios(end+1) = new_s;
            end
            app.record_scenario_change(length(app.EnvCfg.Scenarios));
            app.on_selected_node(app.Scenarios(end)); % not triggered automatically
            expand(app.ExperimentsNode);
        end

        % Button pushed function: RemoveexperimentButton
        function on_remove_scenario(app, event)
            idx = app.get_active_scenario();
            app.remove_scenario(app.Scenarios(idx));
        end

        % Button pushed function: AddmetricButton
        function on_add_metric(app, event)
            app.TabGroup.SelectedTab =  app.EditMetricsTab;
            
            app.Metrics(end+1) = uitreenode(app.MetricsNode, 'Text', 'New_metric');
            app.EnvTree.SelectedNodes = app.Metrics(end);
            id = string(java.util.UUID.randomUUID.toString);
            new_m = struct('Id', id, 'Name', 'New_metric', 'Callback', '', 'Params', struct, 'ParamsStructName', '');
            if isempty(app.EnvCfg.Metrics)
                app.EnvCfg.Metrics = new_m;
            else
                app.EnvCfg.Metrics(end+1) = new_m;
            end
            app.NewMetrics(end+1) = "";
            app.record_metric_change(length(app.EnvCfg.Metrics));
            app.on_selected_node(app.Metrics(end)); % not triggered automatically
            expand(app.MetricsNode);
        end

        % Button pushed function: RemovemetricButton
        function on_remove_metric(app, event)
            idx = app.get_active_metric();
            app.remove_metric(app.Metrics(idx));
        end

        % Value changed function: ScenarioName
        function on_scenario_name_change(app, event)
            value = app.ScenarioName.Value;
            idx = app.get_active_scenario();
            app.EnvCfg.Scenarios(idx).Name = value;
            app.Scenarios(idx).Text = value;

            app.record_scenario_change(idx);
        end

        % Value changed function: ScenarioIc
        function on_ic_value_change(app, event)
            value = app.ScenarioIc.Value;
            idx = app.get_active_scenario();
            app.EnvCfg.Scenarios(idx).Ic = eval(value);

            app.record_scenario_change(idx);
        end

        % Value changed function: MetricName
        function on_metric_name_change(app, event)
            value = app.MetricName.Value;
            idx = app.get_active_metric();
            app.EnvCfg.Metrics(idx).Name = value;
            app.Metrics(idx).Text = value;

            app.record_metric_change(idx);
            
        end

        % Button pushed function: SelectedMetricsCallback
        function on_select_metric_callback(app, event)
            
            
            [file, folder] = uigetfile('lib/plot_functions/*.m');
            if isnumeric(folder)
                return
            end
            file = fullfile(folder, file);
            [folder, name, ext] = fileparts(file);

            if ~is_valid_m_function(name)
                error('Selected file is not a valid m function')
            end

            idx = app.get_active_metric();
            app.Metrics(idx).Text = name;
            app.EnvCfg.Metrics(idx).Name = string(name);
            app.NewMetrics(idx) = string(fullfile(folder, strcat(name, ext)));

            app.EnvCfg.Metrics(idx).ParamsStructName = strcat(name, '_params');
            app.record_metric_change(idx);

            app.on_edit_metrics_select();

        end

        % Button pushed function: CtlSavechangesButton_3
        function on_scenario_save(app, event)
            idx = app.get_active_scenario();
            if strempty(app.EnvCfg.Scenarios(idx).Reference)
                error('Scenario must have selected reference to be saved.');
            end
            app.ScenarioChanges(idx) = 0;
            app.Scenarios(idx).Text = replace(app.Scenarios(idx).Text, '*', '');
            save_env_scenario(app.EnvName, app.EnvCfg.Scenarios(idx));
        end

        % Button pushed function: CtlSavechangesButton_2
        function on_save_metric(app, event)
            
            idx = app.get_active_metric();
            if ~strempty(app.NewMetrics(idx))
                if ~strempty(app.EnvCfg.Metrics(idx).Callback)
                    full_file = fullfile(app.EnvPath, 'autogen', 'metrics', 'private', ...
                        strcat(app.EnvCfg.Metrics(idx).Callback, '.m'));
                    delete(full_file);
                end
                
                ok = copyfile(app.NewMetrics(idx), fullfile(app.EnvPath, 'autogen', 'metrics', 'private'));
                [~, name, ~] = fileparts(app.NewMetrics(idx));
                app.EnvCfg.Metrics(idx).Callback = name;
                app.NewMetrics(idx) = "";
            end

            app.MetricChanges(idx) = 0;
            app.Metrics(idx).Text = replace(app.Metrics(idx).Text, '*', '');
            save_env_metric(app.EnvName, app.EnvCfg.Metrics(idx));
        end

        % Button pushed function: EditreferencesButton
        function on_edit_references(app, event)
            edit_references(app.EnvPath);
        end

        % Button pushed function: SelectedMetricsCallback_2
        function on_select_reference(app, event)
            references = load_env_references(app.EnvPath);
            names = cellfun(@(x) string(x{1}.Name), references);
            function tmp(selection)
                idx = cellfun(@(x) strcmp(x.Name, selection), references);
                app.on_reference_selector_closed(references{idx});
            end
            selector_app(names, @tmp);
        end

        % Value changed function: ConsthorizonreferenceCheckBox
        function on_const_horizon_reference_change(app, event)
            value = app.ConsthorizonreferenceCheckBox.Value;
            idx = app.get_active_scenario();
            app.EnvCfg.Scenarios(idx).ConstHorizonReference = value; 
            app.record_scenario_change(idx);
        end

        % Value changed function: SteptimeEditField
        function on_update_step_time(app, event)
            value = app.SteptimeEditField.Value;
            app.EnvCfg.Metadata.Ts = value;
            app.record_env_change();
        end

        % Button pushed function: ExportsystemButton
        function on_export_system(app, event)
            name = app.EnvCfg.System.Name;
            path = create_comp_export_dir(fullfile('export', name));
            create_export_cdf_file(app.EnvCfg.System, 'system', path)
            save_env_system(path, app.EnvCfg.System, 0);
        end

        % Button pushed function: ExportcontrollerButton
        function on_export_controller(app, event)
            idx = app.get_active_controller();
            ctl = app.EnvCfg.Controllers(idx);
            name = ctl.Name;
            path = create_comp_export_dir(fullfile('export', name));
            create_export_cdf_file(ctl, 'controller', path)
            save_env_controller(path, ctl, 0);
        end

        % Button pushed function: ExportestimatorButton
        function on_export_estimator(app, event)
            idx = app.get_active_controller();
            est = app.EnvCfg.Controllers(idx).Estimator;
            name = est.Name;
            path = create_comp_export_dir(fullfile('export', name));
            create_export_cdf_file(est, 'estimator', path)
            save_env_estimator(path, est, 0);
        end

        % Button pushed function: ExportdisturbanceButton
        function on_export_disturbance(app, event)
            is_ctl = app.is_controller_distrurbance_selected();

            if is_ctl
                idx = app.get_active_controller();
                dist = app.EnvCfg.Controllers(idx).Disturbance;
            else
                dist = app.EnvCfg.System.Disturbance;
            end
            path = create_comp_export_dir(fullfile('export', dist.Name));
            create_export_cdf_file(dist, 'disturbance', path)
            save_env_disturbance(path, dist, 0);
        end

        % Button pushed function: ExportexperimentButton
        function on_export_scenario(app, event)
            idx = app.get_active_scenario();
            s = app.EnvCfg.Scenarios(idx);
            name = s.Name;
            path = create_comp_export_dir(fullfile('export', name));
            save_env_scenario(path, s, 0);
            create_export_cdf_file(s, 'scenario', path)
            export_scenario_reference(app.EnvPath, s, path);
        end

        % Button pushed function: ExportmetricButton
        function on_export_metric(app, event)
            idx = app.get_active_metric();
            m = app.EnvCfg.Metrics(idx);
            name = m.Name;
            path = create_comp_export_dir(fullfile('export', name));
            save_env_metric(path, m, 0);
            create_export_cdf_file(m, 'metric', path)
            app.copy_metrics(path, m);
        end

        % Button pushed function: ImportsystemButton
        function on_import_system(app, event)
           [file, folder] = uigetfile({'.cdf'});

           if isnumeric(folder)
               return
           end

           if ~exist(fullfile(folder, 'parts', 'system.json'), 'file')
                error('Selected file is not a system.');
           end

           check_cdf_library_exists(fullfile(folder, file));
           
           old_id = app.EnvCfg.System.Id;
           app.EnvCfg.System = load_env_system(folder, 0); 
           app.EnvCfg.System.Id = old_id; 
           app.update_system();
           app.record_system_change();
        end

        % Button pushed function: ImportcontrollerButton
        function on_import_controller(app, event)
            [file, folder] = uigetfile({'.cdf'});
            
            if ~isfolder(fullfile(folder, 'parts', 'controllers'))
                error('Selected file is not a controller.');
            end
            id = check_cdf_library_exists(fullfile(folder, file));

            idx = app.get_active_controller();
            old_id = app.EnvCfg.Controllers(idx).Id;

            app.EnvCfg.Controllers(idx) = load_env_controller(folder, id, 0); 
            app.EnvCfg.Controllers(idx).Id = old_id;
            app.update_controller(idx);
            app.record_controller_change(idx);
        end

        % Button pushed function: ImportestimatorButton
        function on_import_estimator(app, event)
            [file, folder] = uigetfile({'.cdf'});
           
            if ~exist(fullfile(folder, 'parts', 'estimator.json'), 'file')
                error('Selected file is not an estimator.');
            end

            check_cdf_library_exists(fullfile(folder, file));

            idx = app.get_active_controller();
            old_id = app.EnvCfg.Controllers(idx).Estimator.Id;
            app.EnvCfg.Controllers(idx).Estimator = load_env_estimator(folder, 0);
            app.EnvCfg.Controllers(idx).Estimator.Id = old_id;
            app.update_controller(idx);
            app.record_controller_change(idx);
        end

        % Button pushed function: ImportdisturbanceButton
        function on_import_disturbance(app, event)
            [file, folder] = uigetfile({'.cdf'});

            if isnumeric(folder)
                return
            end
           
            if ~exist(fullfile(folder, 'parts', 'disturbance.json'), 'file')
                error('Selected file is not a disturbance.');
            end
            check_cdf_library_exists(fullfile(folder, file));
            is_ctl = app.is_controller_distrurbance_selected();

            if is_ctl
                idx = app.get_active_controller();
                old_id = app.EnvCfg.Controllers(idx).Disturbance.Id;

                app.EnvCfg.Controllers(idx).Disturbance = load_env_disturbance(folder, 0); 
                app.EnvCfg.Controllers(idx).Disturbance.Id = old_id;

                app.update_controller(idx);
                app.record_controller_change(idx);
            else
                old_id = app.EnvCfg.System.Disturbance.Id;
                app.EnvCfg.System.Disturbance = load_env_disturbance(folder, 0); 
                app.EnvCfg.System.Disturbance.Id = old_id;

                app.update_system();
                app.record_system_change();
            end
        end

        % Button pushed function: ImportexperimentButton
        function on_import_scenario(app, event)
            [~, folder] = uigetfile({'.cdf'});
           
            if ~exist(fullfile(folder, 'parts', 'scenarios.json'), 'file')
                error('Selected file is not a scenario.');
            end
            idx = app.get_active_scenario();
            old_id = app.EnvCfg.Scenarios(idx).Id;
            app.EnvCfg.Scenarios(idx) = load_env_scenarios(folder, 0); 
            app.EnvCfg.Scenarios(idx).Id = old_id;
            import_scenario_reference(app.EnvPath, app.EnvCfg.Scenarios(idx), folder);
            app.update_scenarios(idx);
            app.record_scenario_change(idx);
        end

        % Button pushed function: ImportmetricButton
        function on_import_metric(app, event)
            [~, folder] = uigetfile({'.cdf'});
           
            if ~exist(fullfile(folder, 'parts', 'metrics.json'), 'file')
                error('Selected file is not a metric.');
            end
            idx = app.get_active_metric();
            old_id = app.EnvCfg.Metrics(idx).Id;
            app.EnvCfg.Metrics(idx) = load_env_metrics(folder, 0); 
            app.EnvCfg.Metrics(idx).Id = old_id;
            app.update_metrics(idx);
            app.record_metric_change(idx);
        end

        % Button pushed function: SavechangesButton_2
        function on_env_save_changes(app, event)
            save_env_metadata(app.EnvPath, app.EnvCfg.Metadata);
            app.EnvChanges = 0;
            app.EnvironmentNode.Text = replace(app.EnvironmentNode.Text, '*', '');
        end

        % Button pushed function: AddfileButton
        function on_add_file(app, event)
            [file, location] = uigetfile("MultiSelect", 'on');
            if isnumeric(file)
                return
            end
            if ~iscell(file)
                file = {file};
            end
            for i=1:length(file)
                app.add_to_context(fullfile(location, file{i}));
            end
        end

        % Button pushed function: DeleteButton
        function on_delete_files(app, event)
            nodes = app.ParamTree.SelectedNodes;
            app.on_remove_selection_from_context(nodes);
        end

        % Button pushed function: AddfolderButton
        function on_add_folder(app, event)
            path = uigetdir("MultiSelect", 'on');
            if isnumeric(path)
                return
            end
            app.add_to_context(path);
        end

        % Button pushed function: AdddisturbanceButtonSys
        function on_add_disturbance_system(app, event)
            sys = app.EnvCfg.System;

            if ~is_valid_field(sys, 'Disturbance')
                app.EnvCfg.System.Disturbance = struct;

                app.AdddisturbanceButtonSys.Text = 'Remove disturbance';
                app.EnvCfg.System.Disturbance = options_as_struct(DisturbanceOptions);
                app.TabGroup.SelectedTab = app.DisturbanceTab;
                data = app.component_node_data('Disturbance', sys.Id);
                app.SysDisturbance = uitreenode(app.SystemNode, 'Text', 'New disturbance', 'NodeData', data);
                app.EnvCfg.System.Disturbance.Name = 'New disturbance';
                app.EnvTree.SelectedNodes = app.SysDisturbance;
                app.update_disturbance(0, 0);
            else
                app.remove_disturbance(app.SysDisturbance, 0);
                app.AdddisturbanceButtonSys.Text = 'Add disturbance';
            end
            app.record_system_change();
        end

        % Value changed function: CoderExtrinsicCheckBox
        function on_coder_extrinsic(app, event)
            value = app.CoderExtrinsicCheckBox.Value;
            app.EnvCfg.Metadata.Extrinsic = value;
            app.record_env_change();
        end

        % Button pushed function: EditcallbacksButton, 
        % ...and 5 other components
        function on_edit_callbacks(app, event)
            
            t = -1;
            node = app.EnvTree.SelectedNodes;
            if isempty(node)
                return
            end

            if startsWith(node.Text, "Environment")
                c = app.EnvCfg.Metadata;
                t = 0;
            elseif startsWith(node.Text, "System") && startsWith(node.Parent.Text, "Environment")
                c = app.EnvCfg.System;
                t = 1;
            elseif strcmp(node.Parent.Text, "Controllers")
                ctl_idx = app.get_active_controller();
                c = app.EnvCfg.Controllers(ctl_idx);
                t = 2;
            elseif app.is_subtype(node, 'Subcontroller')
                ctl_idx = app.get_active_controller();
                ctl = app.EnvCfg.Controllers(ctl_idx);
                sub_idx = app.get_active_subcontroller();
                c = ctl.Components(sub_idx);
                t = 3;
            elseif app.is_subtype(node, 'Estimator')
                ctl_idx = app.get_active_controller();
                c = app.EnvCfg.Controllers(ctl_idx).Estimator;
                t = 4;
            elseif app.is_subtype(node, 'Disturbance')
                is_ctl = app.is_controller_distrurbance_selected();
                if is_ctl
                    t = 5;
                    ctl_idx = app.get_active_controller();
                    c = app.EnvCfg.Controllers(ctl_idx).Disturbance;
                else
                    t = 6;
                    c = app.EnvCfg.System.Disturbance;
                end
            end

            function on_edit_callbacks_done(new_callbacks)
                if t == 0
                    app.EnvCfg.Metadata.Callbacks = new_callbacks;
                    app.record_env_change();
                elseif t == 1
                    app.EnvCfg.System.Callbacks = new_callbacks;
                    app.record_system_change();
                elseif t == 2
                    app.EnvCfg.Controllers(ctl_idx).Callbacks = new_callbacks;
                    app.record_controller_change(ctl_idx);
                elseif t == 3
                    ctl.Components(sub_idx).Callbacks = new_calbacks;
                    app.record_controller_change(ctl_idx);
                elseif t == 4
                    app.EnvCfg.Controllers(ctl_idx).Estimator.Callbacks = new_callbacks;
                    app.record_controller_change(ctl_idx);
                elseif t == 5
                    app.EnvCfg.Controllers(ctl_idx).Disturbance.Callbacks = new_callbacks;
                    app.record_controller_change(ctl_idx);
                elseif t == 6
                    app.EnvCfg.System.Disturbance.Callbacks = new_callbacks;
                    app.record_system_change();

                end
            end
            
            if ~is_valid_field(c, 'Callbacks')
                callbacks = init_env_callbacks;
            else
                callbacks = c.Callbacks;
            end
            edit_callbacks_app(callbacks, @on_edit_callbacks_done);

        end

        % Button pushed function: RefreshParamsButton
        function on_refresh_params(app, event)
                        
            if app.TabGroup.SelectedTab == app.ControllerTab
                selection = app.CtlParamsStructName.Value;
            elseif app.TabGroup.SelectedTab == app.SystemTab
                selection = app.SysParamsStructName.Value;
            elseif app.TabGroup.SelectedTab == app.SubcontrollerTab
                selection = app.SubCtlParamsStructName.Value;
            elseif app.TabGroup.SelectedTab == app.EstimatorTab
                selection = app.EstParamsStructName.Value;
            elseif app.TabGroup.SelectedTab == app.DisturbanceTab
                selection = app.DistParamsStructName.Value;
            elseif app.TabGroup.SelectedTab == app.EditMetricsTab
                selection = strcat(app.MetricCallback.Text, '_params');
            elseif app.TabGroup.SelectedTab == app.EditScenariosTab
                selection = strcat(replace(app.ScenarioName.Value, ' ', '_'), '_params');
            else
                error('Cannot refresh parameters. Try loading instead.');
            end
            try
                p = evalin('base', selection);
            catch
                error(strcat("Cannot find '", selection,"' variable in workspace."));
            end
            app.set_loaded_params(selection, p);
        end

        % Button pushed function: DuplicateexperimentButton
        function on_duplicate_scenario(app, event)
            idx = app.get_active_scenario();
            curr_sc = options_as_struct(app.EnvCfg.Scenarios(idx));
            curr_sc.Id = new_uuid();
            t_now = replace(string(app.unixtime_now), '.', '');
            app.TabGroup.SelectedTab =  app.EditScenariosTab;
            new_name = strcat(curr_sc.Name, "_", t_now);
            curr_sc.Name = new_name;
            curr_sc.ParamsStructName = strcat(curr_sc.ParamsStructName, "_", t_now);
            app.Scenarios(end+1) = uitreenode(app.ExperimentsNode, 'Text', new_name);
            app.EnvTree.SelectedNodes = app.Scenarios(end);
           
            app.EnvCfg.Scenarios(end+1) = curr_sc;
            app.ScenarioChanges(end+1) = 0;
            app.record_scenario_change(length(app.EnvCfg.Scenarios));
            app.on_selected_node(app.EnvTree.SelectedNodes); % not triggered automatically
            expand(app.ExperimentsNode);  
        end

        % Button pushed function: DuplicatemetricButton
        function on_duplicate_metric(app, event)
            idx = app.get_active_metric();
            curr_m = options_as_struct(app.EnvCfg.Metrics(idx));
            curr_m.Id = new_uuid();
            t_now = replace(string(app.unixtime_now), '.', '');
            app.TabGroup.SelectedTab =  app.EditMetricsTab;
            new_name = strcat(curr_m.Name, "_", t_now);
            curr_m.Name = new_name;
            curr_m.ParamsStructName = strcat(curr_m.ParamsStructName, "_", t_now);
            app.Metrics(end+1) = uitreenode(app.MetricsNode, 'Text', new_name);
            app.EnvTree.SelectedNodes = app.Metrics(end);
           
            app.EnvCfg.Metrics(end+1) = curr_m;
            app.MetricChanges(end+1) = 0;
            app.record_metric_change(length(app.EnvCfg.Metrics));
            app.on_selected_node(app.EnvTree.SelectedNodes); % not triggered automatically
            expand(app.MetricsNode);  
        end

        % Button pushed function: GeneratoroptionsButton
        function on_generator_options(app, event)
            
            function on_generator_options_app_close(new_options, inner_app)
                if isempty(new_options)
                    return
                end
                app.GeneratorOptions = new_options;
                save_env_generator_options(app.EnvPath, new_options, 0);

                % trigger generate if generate env button is clicked (event is empty)
                if isempty(event)
                    inner_app.display_generate();
                    app.on_generate_env();
                end
            end

            generator_options_app(app.EnvCfg.Controllers, app.GeneratorOptions, @on_generator_options_app_close);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnvironmentmanagerUIFigure = uifigure('Visible', 'off');
            app.EnvironmentmanagerUIFigure.Name = 'Environment manager';
            app.EnvironmentmanagerUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @on_key_press, true);
            app.EnvironmentmanagerUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @on_key_relese, true);
            app.GridLayout = uigridlayout(app.EnvironmentmanagerUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ParamsPanel = uipanel(app.GridLayout3);
            app.GridLayout10 = uigridlayout(app.ParamsPanel);
            app.ParamTree = uitree(app.GridLayout10);
            app.ParamTree.SelectionChangedFcn = createCallbackFcn(app, @on_param_select, true);
            app.ParamEditPanel = uipanel(app.GridLayout10);
            app.SavetoworkspaceButton = uibutton(app.ParamEditPanel, 'push');
            app.SavetoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @on_save_params_to_workspace, true);
            app.SavetoworkspaceButton.Text = 'Save to workspace';
            app.LoadfromworkspaceButton = uibutton(app.ParamEditPanel, 'push');
            app.LoadfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @on_load_params_from_workspace, true);
            app.LoadfromworkspaceButton.Text = 'Load from workspace';
            app.AddfileButton = uibutton(app.ParamEditPanel, 'push');
            app.AddfileButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_file, true);
            app.AddfileButton.Text = 'Add file';
            app.AddfolderButton = uibutton(app.ParamEditPanel, 'push');
            app.AddfolderButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_folder, true);
            app.AddfolderButton.Text = 'Add folder';
            app.DeleteButton = uibutton(app.ParamEditPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @on_delete_files, true);
            app.DeleteButton.Text = 'Delete';
            app.RefreshParamsButton = uibutton(app.ParamEditPanel, 'push');
            app.RefreshParamsButton.ButtonPushedFcn = createCallbackFcn(app, @on_refresh_params, true);
            app.RefreshParamsButton.Text = 'Refresh parameters';
            app.Panel_5 = uipanel(app.GridLayout2);
            app.Panel_5.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel_5);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @on_selection_changed, true);
            app.EnvTab = uitab(app.TabGroup);
            app.EnvTab.Title = 'Env';
            app.EnvironmentNameEditFieldLabel = uilabel(app.EnvTab);
            app.EnvironmentNameEditFieldLabel.Text = 'Environment Name';
            app.EnvironmentNameEditField = uieditfield(app.EnvTab, 'text');
            app.EnvironmentNameEditField.ValueChangedFcn = createCallbackFcn(app, @on_env_name_change, true);
            app.SteptimeEditFieldLabel = uilabel(app.EnvTab);
            app.SteptimeEditFieldLabel.Text = 'Step time';
            app.SteptimeEditField = uieditfield(app.EnvTab, 'numeric');
            app.SteptimeEditField.ValueChangedFcn = createCallbackFcn(app, @on_update_step_time, true);
            app.SteptimeEditField.Value = 0.01;
            app.CoderExtrinsicCheckBox = uicheckbox(app.EnvTab);
            app.CoderExtrinsicCheckBox.ValueChangedFcn = createCallbackFcn(app, @on_coder_extrinsic, true);
            app.CoderExtrinsicCheckBox.Text = 'Coder Extrinsic';
            app.Panel_21 = uipanel(app.EnvTab);
            app.GridLayout11_2 = uigridlayout(app.Panel_21);
            app.ExportenvironmentButton = uibutton(app.GridLayout11_2, 'push');
            app.ExportenvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @on_export_env, true);
            app.ExportenvironmentButton.Text = 'Export environment';
            app.GenerateenvironmentButton = uibutton(app.GridLayout11_2, 'push');
            app.GenerateenvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @on_generate_env, true);
            app.GenerateenvironmentButton.Text = 'Generate environment';
            app.SavechangesButton_2 = uibutton(app.GridLayout11_2, 'push');
            app.SavechangesButton_2.ButtonPushedFcn = createCallbackFcn(app, @on_env_save_changes, true);
            app.SavechangesButton_2.Text = 'Save changes';
            app.GeneratoroptionsButton = uibutton(app.GridLayout11_2, 'push');
            app.GeneratoroptionsButton.ButtonPushedFcn = createCallbackFcn(app, @on_generator_options, true);
            app.GeneratoroptionsButton.Text = 'Generator options';
            app.EditcallbacksButton = uibutton(app.EnvTab, 'push');
            app.EditcallbacksButton.ButtonPushedFcn = createCallbackFcn(app, @on_edit_callbacks, true);
            app.EditcallbacksButton.Text = 'Edit callbacks';
            app.EnvControlTab = uitab(app.TabGroup);
            app.EnvControlTab.Title = 'EnvControl';
            app.Panel_14 = uipanel(app.EnvControlTab);
            app.GridLayout4_6 = uigridlayout(app.Panel_14);
            app.SystemLabel_3 = uilabel(app.GridLayout4_6);
            app.SystemLabel_3.Text = 'System';
            app.SysNameEnv = uilabel(app.GridLayout4_6);
            app.SysNameEnv.Text = 'SysName';
            app.EditsystemButton = uibutton(app.GridLayout4_6, 'push');
            app.EditsystemButton.ButtonPushedFcn = createCallbackFcn(app, @on_edit_system_click, true);
            app.EditsystemButton.Text = 'Select system';
            app.Panel_13 = uipanel(app.EnvControlTab);
            app.GridLayout9 = uigridlayout(app.Panel_13);
            app.AddcontrollerButton_2 = uibutton(app.GridLayout9, 'push');
            app.AddcontrollerButton_2.ButtonPushedFcn = createCallbackFcn(app, @on_add_controller, true);
            app.AddcontrollerButton_2.Text = 'Add controller';
            app.AddexperimentButton = uibutton(app.GridLayout9, 'push');
            app.AddexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_scenario, true);
            app.AddexperimentButton.Text = 'Add experiment';
            app.AddmetricButton = uibutton(app.GridLayout9, 'push');
            app.AddmetricButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_metric, true);
            app.AddmetricButton.Text = 'Add metric';
            app.EditreferencesButton = uibutton(app.EnvControlTab, 'push');
            app.EditreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @on_edit_references, true);
            app.EditreferencesButton.Text = 'Edit references';
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.SystemNameLabel = uilabel(app.SystemTab);
            app.SystemNameLabel.Text = 'System Name';
            app.SysNameSystem = uieditfield(app.SystemTab, 'text');
            app.SysNameSystem.ValueChangedFcn = createCallbackFcn(app, @on_system_name_change, true);
            app.Panel_7 = uipanel(app.SystemTab);
            app.GridLayout4 = uigridlayout(app.Panel_7);
            app.SystemLabel_2 = uilabel(app.GridLayout4);
            app.SystemLabel_2.Text = 'System';
            app.SysTypeLabel = uilabel(app.GridLayout4);
            app.SysTypeLabel.Text = 'SysType';
            app.LibLabel = uilabel(app.GridLayout4);
            app.LibLabel.Text = 'Lib';
            app.SysLibLabel = uilabel(app.GridLayout4);
            app.SysLibLabel.Text = 'SysLib';
            app.SelectsystemButton = uibutton(app.GridLayout4, 'push');
            app.SelectsystemButton.ButtonPushedFcn = createCallbackFcn(app, @on_select_system_btn, true);
            app.SelectsystemButton.Text = 'Select system';
            app.SystemNameLabel_2 = uilabel(app.SystemTab);
            app.SystemNameLabel_2.Text = 'Param struct';
            app.SysParamsStructName = uieditfield(app.SystemTab, 'text');
            app.SysParamsStructName.ValueChangedFcn = createCallbackFcn(app, @on_system_param_struct_change, true);
            app.Panel_22 = uipanel(app.SystemTab);
            app.GridLayout11_3 = uigridlayout(app.Panel_22);
            app.SavechangesButton = uibutton(app.GridLayout11_3, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @on_system_save_changes, true);
            app.SavechangesButton.Text = 'Save changes';
            app.AdddisturbanceButtonSys = uibutton(app.GridLayout11_3, 'push');
            app.AdddisturbanceButtonSys.ButtonPushedFcn = createCallbackFcn(app, @on_add_disturbance_system, true);
            app.AdddisturbanceButtonSys.Text = 'Add disturbance';
            app.ExportsystemButton = uibutton(app.GridLayout11_3, 'push');
            app.ExportsystemButton.ButtonPushedFcn = createCallbackFcn(app, @on_export_system, true);
            app.ExportsystemButton.Text = 'Export system';
            app.ImportsystemButton = uibutton(app.GridLayout11_3, 'push');
            app.ImportsystemButton.ButtonPushedFcn = createCallbackFcn(app, @on_import_system, true);
            app.ImportsystemButton.Text = 'Import system';
            app.EditcallbacksButton_2 = uibutton(app.SystemTab, 'push');
            app.EditcallbacksButton_2.ButtonPushedFcn = createCallbackFcn(app, @on_edit_callbacks, true);
            app.EditcallbacksButton_2.Text = 'Edit callbacks';
            app.ControllerTab = uitab(app.TabGroup);
            app.ControllerTab.Title = 'Controller';
            app.CtlPluginPanel = uipanel(app.ControllerTab);
            app.GridLayout4_2 = uigridlayout(app.CtlPluginPanel);
            app.ControllerLabel = uilabel(app.GridLayout4_2);
            app.ControllerLabel.Text = 'Controller';
            app.CtlTypeLabel = uilabel(app.GridLayout4_2);
            app.CtlTypeLabel.Text = '-';
            app.LibLabel_2 = uilabel(app.GridLayout4_2);
            app.LibLabel_2.Text = 'Lib';
            app.CtlLibLabel = uilabel(app.GridLayout4_2);
            app.CtlLibLabel.Text = '-';
            app.SelectconrollerButton = uibutton(app.GridLayout4_2, 'push');
            app.SelectconrollerButton.ButtonPushedFcn = createCallbackFcn(app, @on_select_controller_plugin, true);
            app.SelectconrollerButton.Text = 'Select controller';
            app.ControllerNameLabel = uilabel(app.ControllerTab);
            app.ControllerNameLabel.Text = 'Controller Name';
            app.CtlName = uieditfield(app.ControllerTab, 'text');
            app.CtlName.ValueChangedFcn = createCallbackFcn(app, @on_controller_name_changed, true);
            app.SystemNameLabel_3 = uilabel(app.ControllerTab);
            app.SystemNameLabel_3.Text = 'Param struct';
            app.CtlParamsStructName = uieditfield(app.ControllerTab, 'text');
            app.CtlParamsStructName.ValueChangedFcn = createCallbackFcn(app, @on_controller_param_struct_change, true);
            app.IscomposableCheckBox = uicheckbox(app.ControllerTab);
            app.IscomposableCheckBox.ValueChangedFcn = createCallbackFcn(app, @on_is_composable_change, true);
            app.IscomposableCheckBox.Text = 'Is composable';
            app.AddcomponentButton = uibutton(app.ControllerTab, 'push');
            app.AddcomponentButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_controller_component, true);
            app.AddcomponentButton.Visible = 'off';
            app.AddcomponentButton.Text = 'Add component';
            app.CtlMuxPanel = uipanel(app.ControllerTab);
            app.SystemNameLabel_9 = uilabel(app.CtlMuxPanel);
            app.SystemNameLabel_9.Text = 'Mux inputs';
            app.CtlMuxInputs = uieditfield(app.CtlMuxPanel, 'text');
            app.CtlMuxInputs.ValueChangedFcn = createCallbackFcn(app, @on_controller_mux_inputs_change, true);
            app.RefhorizonEditFieldLabel = uilabel(app.CtlMuxPanel);
            app.RefhorizonEditFieldLabel.Text = 'Ref horizon';
            app.CtlRefHorizon = uieditfield(app.CtlMuxPanel, 'numeric');
            app.CtlRefHorizon.ValueChangedFcn = createCallbackFcn(app, @on_controller_ref_horizon_change, true);
            app.SystemNameLabel_10 = uilabel(app.CtlMuxPanel);
            app.SystemNameLabel_10.Text = 'Mux outputs';
            app.CtlMuxOutputs = uieditfield(app.CtlMuxPanel, 'text');
            app.CtlMuxOutputs.ValueChangedFcn = createCallbackFcn(app, @on_controller_mux_outputs_change, true);
            app.Panel_23 = uipanel(app.ControllerTab);
            app.GridLayout11_4 = uigridlayout(app.Panel_23);
            app.RemovecontrollerButton = uibutton(app.GridLayout11_4, 'push');
            app.RemovecontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @on_remove_controller, true);
            app.RemovecontrollerButton.Text = 'Remove controller';
            app.DuplicatecontrollerButton = uibutton(app.GridLayout11_4, 'push');
            app.DuplicatecontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @on_duplicate_controller, true);
            app.DuplicatecontrollerButton.Text = 'Duplicate controller';
            app.CtlSavechangesButton = uibutton(app.GridLayout11_4, 'push');
            app.CtlSavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @on_save_controller, true);
            app.CtlSavechangesButton.Text = 'Save changes';
            app.AddestimatorButton = uibutton(app.GridLayout11_4, 'push');
            app.AddestimatorButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_estimator, true);
            app.AddestimatorButton.Text = 'Add estimator';
            app.AdddisturbanceButton = uibutton(app.GridLayout11_4, 'push');
            app.AdddisturbanceButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_disturbance, true);
            app.AdddisturbanceButton.Text = 'Add disturbance';
            app.ExportcontrollerButton = uibutton(app.GridLayout11_4, 'push');
            app.ExportcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @on_export_controller, true);
            app.ExportcontrollerButton.Text = 'Export controller';
            app.ImportcontrollerButton = uibutton(app.GridLayout11_4, 'push');
            app.ImportcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @on_import_controller, true);
            app.ImportcontrollerButton.Text = 'Import controller';
            app.EditcallbacksButton_3 = uibutton(app.ControllerTab, 'push');
            app.EditcallbacksButton_3.ButtonPushedFcn = createCallbackFcn(app, @on_edit_callbacks, true);
            app.EditcallbacksButton_3.Text = 'Edit callbacks';
            app.EstimatorTab = uitab(app.TabGroup);
            app.EstimatorTab.Title = 'Estimator';
            app.ControllerNameLabel_2 = uilabel(app.EstimatorTab);
            app.ControllerNameLabel_2.Text = 'Estimator Name';
            app.EstName = uieditfield(app.EstimatorTab, 'text');
            app.EstName.ValueChangedFcn = createCallbackFcn(app, @on_estimator_name_change, true);
            app.Panel_10 = uipanel(app.EstimatorTab);
            app.GridLayout4_3 = uigridlayout(app.Panel_10);
            app.EstimatorLabel = uilabel(app.GridLayout4_3);
            app.EstimatorLabel.Text = 'Estimator';
            app.EstTypeLabel = uilabel(app.GridLayout4_3);
            app.EstTypeLabel.Text = '-';
            app.LibLabel_3 = uilabel(app.GridLayout4_3);
            app.LibLabel_3.Text = 'Lib';
            app.EstLibLabel = uilabel(app.GridLayout4_3);
            app.EstLibLabel.Text = '-';
            app.SelectEstimatorButton = uibutton(app.GridLayout4_3, 'push');
            app.SelectEstimatorButton.ButtonPushedFcn = createCallbackFcn(app, @on_select_estimator, true);
            app.SelectEstimatorButton.Text = 'Select estimator';
            app.SystemNameLabel_4 = uilabel(app.EstimatorTab);
            app.SystemNameLabel_4.Text = 'Param struct';
            app.EstParamsStructName = uieditfield(app.EstimatorTab, 'text');
            app.EstParamsStructName.ValueChangedFcn = createCallbackFcn(app, @on_estimator_param_struct_name_change, true);
            app.Panel_24 = uipanel(app.EstimatorTab);
            app.GridLayout11_5 = uigridlayout(app.Panel_24);
            app.ExportestimatorButton = uibutton(app.GridLayout11_5, 'push');
            app.ExportestimatorButton.ButtonPushedFcn = createCallbackFcn(app, @on_export_estimator, true);
            app.ExportestimatorButton.Text = 'Export estimator';
            app.ImportestimatorButton = uibutton(app.GridLayout11_5, 'push');
            app.ImportestimatorButton.ButtonPushedFcn = createCallbackFcn(app, @on_import_estimator, true);
            app.ImportestimatorButton.Text = 'Import estimator';
            app.EditcallbacksButton_4 = uibutton(app.EstimatorTab, 'push');
            app.EditcallbacksButton_4.ButtonPushedFcn = createCallbackFcn(app, @on_edit_callbacks, true);
            app.EditcallbacksButton_4.Text = 'Edit callbacks';
            app.DisturbanceTab = uitab(app.TabGroup);
            app.DisturbanceTab.Title = 'Disturbance';
            app.ControllerNameLabel_3 = uilabel(app.DisturbanceTab);
            app.ControllerNameLabel_3.Text = 'Disturbance Name';
            app.DistName = uieditfield(app.DisturbanceTab, 'text');
            app.DistName.ValueChangedFcn = createCallbackFcn(app, @on_disturbance_name_change, true);
            app.Panel_11 = uipanel(app.DisturbanceTab);
            app.GridLayout4_4 = uigridlayout(app.Panel_11);
            app.DisturbanceLabel = uilabel(app.GridLayout4_4);
            app.DisturbanceLabel.Text = 'Disturbance';
            app.DistTypeLabel = uilabel(app.GridLayout4_4);
            app.DistTypeLabel.Text = '-';
            app.LibLabel_4 = uilabel(app.GridLayout4_4);
            app.LibLabel_4.Text = 'Lib';
            app.DistLibLabel = uilabel(app.GridLayout4_4);
            app.DistLibLabel.Text = '-';
            app.SelectEstimatorButton_2 = uibutton(app.GridLayout4_4, 'push');
            app.SelectEstimatorButton_2.ButtonPushedFcn = createCallbackFcn(app, @on_select_disturbance, true);
            app.SelectEstimatorButton_2.Text = 'Select disturbance';
            app.SystemNameLabel_5 = uilabel(app.DisturbanceTab);
            app.SystemNameLabel_5.Text = 'Param struct';
            app.DistParamsStructName = uieditfield(app.DisturbanceTab, 'text');
            app.DistParamsStructName.ValueChangedFcn = createCallbackFcn(app, @on_disturbance_param_struct_name_change, true);
            app.Panel_25 = uipanel(app.DisturbanceTab);
            app.GridLayout11_6 = uigridlayout(app.Panel_25);
            app.ExportdisturbanceButton = uibutton(app.GridLayout11_6, 'push');
            app.ExportdisturbanceButton.ButtonPushedFcn = createCallbackFcn(app, @on_export_disturbance, true);
            app.ExportdisturbanceButton.Text = 'Export disturbance';
            app.ImportdisturbanceButton = uibutton(app.GridLayout11_6, 'push');
            app.ImportdisturbanceButton.ButtonPushedFcn = createCallbackFcn(app, @on_import_disturbance, true);
            app.ImportdisturbanceButton.Text = 'Import disturbance';
            app.EditcallbacksButton_5 = uibutton(app.DisturbanceTab, 'push');
            app.EditcallbacksButton_5.ButtonPushedFcn = createCallbackFcn(app, @on_edit_callbacks, true);
            app.EditcallbacksButton_5.Text = 'Edit callbacks';
            app.SubcontrollerTab = uitab(app.TabGroup);
            app.SubcontrollerTab.Title = 'Subcontroller';
            app.Panel_12 = uipanel(app.SubcontrollerTab);
            app.GridLayout4_5 = uigridlayout(app.Panel_12);
            app.ControllerLabel_2 = uilabel(app.GridLayout4_5);
            app.ControllerLabel_2.Text = 'Controller';
            app.SubCtlTypeLabel = uilabel(app.GridLayout4_5);
            app.SubCtlTypeLabel.Text = '-';
            app.LibLabel_5 = uilabel(app.GridLayout4_5);
            app.LibLabel_5.Text = 'Lib';
            app.SubCtlLibLabel = uilabel(app.GridLayout4_5);
            app.SubCtlLibLabel.Text = '-';
            app.SelectconrollerBtn = uibutton(app.GridLayout4_5, 'push');
            app.SelectconrollerBtn.ButtonPushedFcn = createCallbackFcn(app, @on_select_subcontroller_plugin, true);
            app.SelectconrollerBtn.Text = 'Select subcontroller';
            app.ControllerNameLabel_4 = uilabel(app.SubcontrollerTab);
            app.ControllerNameLabel_4.Text = 'Controller Name';
            app.SubCtlName = uieditfield(app.SubcontrollerTab, 'text');
            app.SubCtlName.ValueChangedFcn = createCallbackFcn(app, @on_subcontroller_name_change, true);
            app.SystemNameLabel_6 = uilabel(app.SubcontrollerTab);
            app.SystemNameLabel_6.Text = 'Param struct';
            app.SubCtlParamsStructName = uieditfield(app.SubcontrollerTab, 'text');
            app.SubCtlParamsStructName.ValueChangedFcn = createCallbackFcn(app, @on_subcontroller_param_struct_change, true);
            app.SubCtlMuxPanel = uipanel(app.SubcontrollerTab);
            app.SystemNameLabel_11 = uilabel(app.SubCtlMuxPanel);
            app.SystemNameLabel_11.Text = 'Mux inputs';
            app.SubCtlMuxInputs = uieditfield(app.SubCtlMuxPanel, 'text');
            app.SubCtlMuxInputs.ValueChangedFcn = createCallbackFcn(app, @on_subcontroller_mux_inputs_change, true);
            app.RefhorizonEditField_2Label = uilabel(app.SubCtlMuxPanel);
            app.RefhorizonEditField_2Label.Text = 'Ref horizon';
            app.SubCtlRefHorizon = uieditfield(app.SubCtlMuxPanel, 'numeric');
            app.SubCtlRefHorizon.ValueChangedFcn = createCallbackFcn(app, @on_subcontroller_ref_horizon_change, true);
            app.SystemNameLabel_12 = uilabel(app.SubCtlMuxPanel);
            app.SystemNameLabel_12.Text = 'Mux outputs';
            app.SubCtlMuxOutputs = uieditfield(app.SubCtlMuxPanel, 'text');
            app.SubCtlMuxOutputs.ValueChangedFcn = createCallbackFcn(app, @on_subcontroller_mux_outputs_change, true);
            app.Panel_26 = uipanel(app.SubcontrollerTab);
            app.GridLayout11_7 = uigridlayout(app.Panel_26);
            app.RemovesubcontrollerButton = uibutton(app.GridLayout11_7, 'push');
            app.RemovesubcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @on_remove_subcontroller, true);
            app.RemovesubcontrollerButton.Text = 'Remove subcontroller';
            app.DuplicatesubcontrollerButton = uibutton(app.GridLayout11_7, 'push');
            app.DuplicatesubcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @on_duplicate_subcontroller, true);
            app.DuplicatesubcontrollerButton.Text = 'Duplicate subcontroller';
            app.EditcallbacksButton_6 = uibutton(app.SubcontrollerTab, 'push');
            app.EditcallbacksButton_6.ButtonPushedFcn = createCallbackFcn(app, @on_edit_callbacks, true);
            app.EditcallbacksButton_6.Text = 'Edit callbacks';
            app.EditScenariosTab = uitab(app.TabGroup);
            app.EditScenariosTab.Title = 'Scenarios';
            app.NameEditField_4Label = uilabel(app.EditScenariosTab);
            app.NameEditField_4Label.Text = 'Name';
            app.ScenarioName = uieditfield(app.EditScenariosTab, 'text');
            app.ScenarioName.ValueChangedFcn = createCallbackFcn(app, @on_scenario_name_change, true);
            app.ICEditFieldLabel = uilabel(app.EditScenariosTab);
            app.ICEditFieldLabel.Text = 'IC';
            app.ScenarioIc = uieditfield(app.EditScenariosTab, 'text');
            app.ScenarioIc.ValueChangedFcn = createCallbackFcn(app, @on_ic_value_change, true);
            app.Panel_20 = uipanel(app.EditScenariosTab);
            app.GridLayout7_4 = uigridlayout(app.Panel_20);
            app.ReferenceLabel = uilabel(app.GridLayout7_4);
            app.ReferenceLabel.Text = 'Reference';
            app.SelectedMetricsCallback_2 = uibutton(app.GridLayout7_4, 'push');
            app.SelectedMetricsCallback_2.ButtonPushedFcn = createCallbackFcn(app, @on_select_reference, true);
            app.SelectedMetricsCallback_2.Text = 'Select reference';
            app.ScenarioReference = uilabel(app.GridLayout7_4);
            app.ScenarioReference.Text = '-';
            app.ConsthorizonreferenceCheckBox = uicheckbox(app.EditScenariosTab);
            app.ConsthorizonreferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @on_const_horizon_reference_change, true);
            app.ConsthorizonreferenceCheckBox.Text = 'Const horizon reference';
            app.Panel_27 = uipanel(app.EditScenariosTab);
            app.GridLayout11_8 = uigridlayout(app.Panel_27);
            app.CtlSavechangesButton_3 = uibutton(app.GridLayout11_8, 'push');
            app.CtlSavechangesButton_3.ButtonPushedFcn = createCallbackFcn(app, @on_scenario_save, true);
            app.CtlSavechangesButton_3.Text = 'Save changes';
            app.RemoveexperimentButton = uibutton(app.GridLayout11_8, 'push');
            app.RemoveexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @on_remove_scenario, true);
            app.RemoveexperimentButton.Text = 'Remove experiment';
            app.ExportexperimentButton = uibutton(app.GridLayout11_8, 'push');
            app.ExportexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @on_export_scenario, true);
            app.ExportexperimentButton.Text = 'Export experiment';
            app.ImportexperimentButton = uibutton(app.GridLayout11_8, 'push');
            app.ImportexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @on_import_scenario, true);
            app.ImportexperimentButton.Text = 'Import experiment';
            app.DuplicateexperimentButton = uibutton(app.GridLayout11_8, 'push');
            app.DuplicateexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @on_duplicate_scenario, true);
            app.DuplicateexperimentButton.Text = 'Duplicate experiment';
            app.EditMetricsTab = uitab(app.TabGroup);
            app.EditMetricsTab.Title = 'Metrics';
            app.NameEditField_3Label = uilabel(app.EditMetricsTab);
            app.NameEditField_3Label.Text = 'Name';
            app.MetricName = uieditfield(app.EditMetricsTab, 'text');
            app.MetricName.ValueChangedFcn = createCallbackFcn(app, @on_metric_name_change, true);
            app.Panel_17 = uipanel(app.EditMetricsTab);
            app.GridLayout7 = uigridlayout(app.Panel_17);
            app.CallbackLabel = uilabel(app.GridLayout7);
            app.CallbackLabel.Text = 'Callback';
            app.SelectedMetricsCallback = uibutton(app.GridLayout7, 'push');
            app.SelectedMetricsCallback.ButtonPushedFcn = createCallbackFcn(app, @on_select_metric_callback, true);
            app.SelectedMetricsCallback.Text = 'Select callback';
            app.MetricCallback = uilabel(app.GridLayout7);
            app.MetricCallback.Text = '-';
            app.Panel_28 = uipanel(app.EditMetricsTab);
            app.GridLayout11_9 = uigridlayout(app.Panel_28);
            app.CtlSavechangesButton_2 = uibutton(app.GridLayout11_9, 'push');
            app.CtlSavechangesButton_2.ButtonPushedFcn = createCallbackFcn(app, @on_save_metric, true);
            app.CtlSavechangesButton_2.Text = 'Save changes';
            app.RemovemetricButton = uibutton(app.GridLayout11_9, 'push');
            app.RemovemetricButton.ButtonPushedFcn = createCallbackFcn(app, @on_remove_metric, true);
            app.RemovemetricButton.Text = 'Remove metric';
            app.ExportmetricButton = uibutton(app.GridLayout11_9, 'push');
            app.ExportmetricButton.ButtonPushedFcn = createCallbackFcn(app, @on_export_metric, true);
            app.ExportmetricButton.Text = 'Export metric';
            app.ImportmetricButton = uibutton(app.GridLayout11_9, 'push');
            app.ImportmetricButton.ButtonPushedFcn = createCallbackFcn(app, @on_import_metric, true);
            app.ImportmetricButton.Text = 'Import metric';
            app.DuplicatemetricButton = uibutton(app.GridLayout11_9, 'push');
            app.DuplicatemetricButton.ButtonPushedFcn = createCallbackFcn(app, @on_duplicate_metric, true);
            app.DuplicatemetricButton.Text = 'Duplicate metric';
            app.HideTabsLabel = uilabel(app.Panel_5);
            app.HideTabsLabel.Visible = 'off';
            app.HideTabsLabel.Text = '';
            app.Name = uilabel(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.EnvTree = uitree(app.GridLayout11);
            app.EnvTree.SelectionChangedFcn = createCallbackFcn(app, @on_selected_node, true);
            app.EnvironmentNode = uitreenode(app.EnvTree);
            app.EnvironmentNode.Text = 'Environment';
            app.SystemNode = uitreenode(app.EnvironmentNode);
            app.SystemNode.Text = 'System';
            app.ExperimentsNode = uitreenode(app.EnvironmentNode);
            app.ExperimentsNode.Text = 'Experiments';
            app.MetricsNode = uitreenode(app.EnvironmentNode);
            app.MetricsNode.Text = 'Metrics';
            app.ControllersNode = uitreenode(app.EnvironmentNode);
            app.ControllersNode.Text = 'Controllers';
            % Show the figure after all components are created
            app.EnvironmentmanagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = env_manager_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnvironmentmanagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnvironmentmanagerUIFigure)
        end
    end
end