classdef generator_options_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout6                 matlab.ui.container.GridLayout
        UpButton                    matlab.ui.control.Button
        DownButton_2                matlab.ui.control.Button
        SelectedCtl                 matlab.ui.control.ListBox
        CtlList                     matlab.ui.control.ListBox
        GridLayout4                 matlab.ui.container.GridLayout
        RemoveallButton             matlab.ui.control.Button
        AddallButton                matlab.ui.control.Button
        RemoveButton                matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        GridLayout2                 matlab.ui.container.GridLayout
        GeneratingenvironmentLabel  matlab.ui.control.Label
        GridLayout2_2               matlab.ui.container.GridLayout
        SaveButton                  matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        SelectcontrollersLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        OnClosedHandle
        Controllers
    end
    
    methods (Access = private)
        

        function set_generator_options(app, controllers, options)
            
            l = length(controllers);
            if isempty(options)
                app.fill_controllers(linspace(1, l, l))
                app.fill_selected([]);
                return
            end

            controller_ids = {controllers.Id};

            indices = [];
            for i=1:length(options.SelectedIds)
                controller_indices_mask = ...
                    cellfun(@(x) strcmp(x, options.SelectedIds(i)), controller_ids);
                idx = find(controller_indices_mask);
                if any(controller_indices_mask)
                    indices = [indices, idx];
                end
            end
            rest_indices = app.create_rest_idx(indices);

            app.fill_controllers(rest_indices);
            app.fill_selected(indices);
        end


        function fill_controllers(app, idx)
            app.CtlList.ItemsData = idx;
            if isempty(idx)
                app.CtlList.Items = strings(0);
            else
                app.CtlList.Items = [app.Controllers(idx).Name];
            end
        end

        function fill_selected(app, idx)
            app.SelectedCtl.ItemsData = idx;
            if isempty(idx)
                app.SelectedCtl.Items = strings(0);
            else
                app.SelectedCtl.Items = [app.Controllers(idx).Name];
            end
        end


        function rest = create_rest_idx(app, idx)
            l = length(app.Controllers);
            rest = linspace(1, l, l);
            rest(idx) = [];
        end


        function ids = get_ids_from_indices(app, indices)
            ids = [];
            for i=1:length(indices)
                ids = [ids, app.Controllers(indices(i)).Id];
            end
        end


    end

    methods (Access = public)
        function display_generate(app)
            app.GeneratingenvironmentLabel.Visible = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, controllers, options, on_closed_handle)
            app.OnClosedHandle = on_closed_handle;
            app.Controllers = controllers;
            app.GeneratingenvironmentLabel.Visible = "off";
            app.set_generator_options(controllers, options);
        end

        % Button pushed function: CancelButton
        function on_cancel(app, event)
            app.OnClosedHandle([], app);
            close(app.UIFigure);            
        end

        % Button pushed function: SaveButton
        function on_save(app, event)
            options.SelectedIds = app.get_ids_from_indices(app.SelectedCtl.ItemsData);
            app.OnClosedHandle(options, app);
            close(app.UIFigure);
        end

        % Button pushed function: AddButton
        function on_add_selection(app, event)
            idxes = app.CtlList.ValueIndex;
            if isempty(idxes)
                return
            end
            newly_select_idxes = app.CtlList.ItemsData(idxes);
            select_idxes = [app.SelectedCtl.ItemsData, newly_select_idxes];
            rest = app.create_rest_idx(select_idxes);
            app.fill_controllers(rest);
            app.fill_selected(select_idxes);
        end

        % Button pushed function: RemoveButton
        function on_remove_selection(app, event)
            idxes = app.SelectedCtl.ValueIndex;
            if isempty(idxes)
                return
            end
            newly_select_idxes = app.SelectedCtl.ItemsData(idxes);
            select_idxes = [app.CtlList.ItemsData, newly_select_idxes];
            select_idxes = sort(select_idxes);
            rest = app.create_rest_idx(select_idxes);
            app.fill_controllers(select_idxes);
            app.fill_selected(rest);
        end

        % Button pushed function: AddallButton
        function on_add_all(app, event)
            indices = app.CtlList.ItemsData;
            select_idxes = [app.SelectedCtl.ItemsData, indices];
            app.fill_controllers([])
            app.fill_selected(select_idxes);
        end

        % Button pushed function: RemoveallButton
        function on_remove_all(app, event)
            app.fill_controllers(linspace(1, length(app.Controllers), length(app.Controllers)));
            app.fill_selected([]);
        end

        % Button pushed function: DownButton_2
        function on_bring_down(app, event)
            if isempty(app.SelectedCtl.ValueIndex) ...
                || length(app.SelectedCtl.ValueIndex) > 1
                return
            end
            idx = app.SelectedCtl.ValueIndex;
            next_idx = idx + 1;
            if next_idx > length(app.Controllers)
                return
            end

            v = app.SelectedCtl.ItemsData(idx);
            app.SelectedCtl.ItemsData(idx) = app.SelectedCtl.ItemsData(next_idx);
            app.SelectedCtl.ItemsData(next_idx) = v;
            app.fill_selected(app.SelectedCtl.ItemsData);
            app.SelectedCtl.ValueIndex = next_idx;
        end

        % Button pushed function: UpButton
        function on_bring_up(app, event)
            if isempty(app.SelectedCtl.ValueIndex) ...
                || length(app.SelectedCtl.ValueIndex) > 1
                return
            end
            idx = app.SelectedCtl.ValueIndex;
            prev_idx = idx - 1;
            if prev_idx <= 0
                return
            end

            v = app.SelectedCtl.ItemsData(idx);
            app.SelectedCtl.ItemsData(idx) = app.SelectedCtl.ItemsData(prev_idx);
            app.SelectedCtl.ItemsData(prev_idx) = v;
            app.fill_selected(app.SelectedCtl.ItemsData);
            app.SelectedCtl.ValueIndex = prev_idx;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectcontrollersLabel = uilabel(app.GridLayout);
            app.SelectcontrollersLabel.Text = 'Select controllers';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout2_2 = uigridlayout(app.GridLayout2);
            app.CancelButton = uibutton(app.GridLayout2_2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @on_cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.GridLayout2_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @on_save, true);
            app.SaveButton.Text = 'Save';
            app.GeneratingenvironmentLabel = uilabel(app.GridLayout2);
            app.GeneratingenvironmentLabel.Text = 'Generating environment...';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_selection, true);
            app.AddButton.Text = {'Add'; '==>'};
            app.RemoveButton = uibutton(app.GridLayout4, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @on_remove_selection, true);
            app.RemoveButton.Text = {'Remove'; '<=='};
            app.AddallButton = uibutton(app.GridLayout4, 'push');
            app.AddallButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_all, true);
            app.AddallButton.Text = 'Add all';
            app.RemoveallButton = uibutton(app.GridLayout4, 'push');
            app.RemoveallButton.ButtonPushedFcn = createCallbackFcn(app, @on_remove_all, true);
            app.RemoveallButton.Text = 'Remove all';
            app.CtlList = uilistbox(app.GridLayout3);
            app.CtlList.Multiselect = 'on';
            app.CtlList.Value = {'Item 1'};
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.SelectedCtl = uilistbox(app.GridLayout5);
            app.SelectedCtl.Multiselect = 'on';
            app.SelectedCtl.Value = {'Item 1'};
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.DownButton_2 = uibutton(app.GridLayout6, 'push');
            app.DownButton_2.ButtonPushedFcn = createCallbackFcn(app, @on_bring_down, true);
            app.DownButton_2.Text = 'Down';
            app.UpButton = uibutton(app.GridLayout6, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @on_bring_up, true);
            app.UpButton.Text = 'Up';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generator_options_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end