classdef plugin_manager_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PluginManagerUIFigure       matlab.ui.Figure
        GridLayout6                 matlab.ui.container.GridLayout
        GridLayout10                matlab.ui.container.GridLayout
        Context                     matlab.ui.container.Tree
        GridLayout11                matlab.ui.container.GridLayout
        OpencontextButton           matlab.ui.control.Button
        DeleteButton                matlab.ui.control.Button
        AddfolderButton             matlab.ui.control.Button
        AddfileButton               matlab.ui.control.Button
        GridLayout9                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        SysTab                      matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        SysTable                    matlab.ui.control.Table
        CtlTab                      matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        CtlTable                    matlab.ui.control.Table
        EstTab                      matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        EstTable                    matlab.ui.control.Table
        DistTab                     matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        DistTable                   matlab.ui.control.Table
        GridLayout12                matlab.ui.container.GridLayout
        RefreshlibraryButton        matlab.ui.control.Button
        UnregisterselectedcomponentsButton  matlab.ui.control.Button
        RegisternewcomponentButton  matlab.ui.control.Button
        GridLayout7                 matlab.ui.container.GridLayout
        GridLayout8                 matlab.ui.container.GridLayout
        LinklibraryButton           matlab.ui.control.Button
        UnregisterlibraryButton     matlab.ui.control.Button
        ExportlibraryButton         matlab.ui.control.Button
        ImportlibraryButton         matlab.ui.control.Button
        CreatenewlibraryButton      matlab.ui.control.Button
        LibrariesLabel              matlab.ui.control.Label
        Tree                        matlab.ui.container.Tree
    end

    
    properties (Access = private)
        LocalLib % Description
        TableData % Description
        TreeData
        Plugins
        Libs
    end
    
    methods (Access = private)
        
        function results = load_tables(app)
           
            app.Libs = list_component_libraries();
            app.Plugins = get_available_plugins();
            
            delete(app.Tree.Children);
            app.TreeData = matlab.ui.container.TreeNode.empty;
            for i=1:length(app.Libs)
                app.TreeData(end+1) = uitreenode(app.Tree, 'Text', app.Libs(i).Name);
            end
        end


        function path = get_lib_context_path(app, lib)
            path = fullfile(lib.Path, lib.Name);
        end

        function l = update_context(app, node)
            lib = app.get_active_library();

            context_path = app.get_lib_context_path(lib);
            l = dir(context_path);
            delete(app.Context.Children);
            
            for i=1:length(l)
                if strcmp(l(i).name, '.') || strcmp(l(i).name, '..')
                    continue
                end
                data.Path = fullfile(context_path, l(i).name);
                uitreenode(app.Context, 'Text', l(i).name, 'NodeData', data);
            end

        end

        function mat = struct_as_mat(app, str)
            mat = strings(length(str), 2);
            for i=1:length(str)
                mat(i, :) = [string(str(i).Name), string(str(i).Type)];
            end
        end


        function l = get_active_library(app)
            idx = find(app.Tree.Children == app.Tree.SelectedNodes);
            l = app.Libs(idx);
        end


        function t = plugin_exists(app, name, lib)
            t = 1;
            c = app.Plugins{lib};

            for i=1:length(c.sys)
                if strcmp(c.sys(i).Name, name)
                    return
                end
            end
            for i=1:length(c.ctl)
                if strcmp(c.ctl(i).Name, name)
                    return
                end
            end
            for i=1:length(c.est)
                if strcmp(c.est(i).Name, name)
                    return
                end
            end
            for i=1:length(c.dist)
                if strcmp(c.dist(i).Name, name)
                    return
                end
            end
            t = 0;
        end

        function update_tables(app, node)
            if isempty(node)
                return
            end
            active_lib = app.Plugins{node.Text};
            app.SysTable.Data = app.struct_as_mat(active_lib.sys);          
            app.CtlTable.Data = app.struct_as_mat(active_lib.ctl);          
            app.EstTable.Data = app.struct_as_mat(active_lib.est);          
            app.DistTable.Data = app.struct_as_mat(active_lib.dist); 
        end

        function idx = idx_plugin(app, p_list, name)
            idx = [];
            if isempty(p_list)
                return
            end
            idx = strcmp([p_list.Name], name);
        end

        function remove_plugin(app, name, t, lib)
            if t == 1
                idx = app.idx_plugin(app.Plugins{lib}.sys, name);
                app.Plugins{lib}.sys(idx) = [];
            elseif t == 2
                idx = app.idx_plugin(app.Plugins{lib}.ctl, name);
                app.Plugins{lib}.ctl(idx) = [];
            elseif t == 3
                idx = app.idx_plugin(app.Plugins{lib}.est, name);
                app.Plugins{lib}.est(idx) = [];
            elseif t == 4
                idx = app.idx_plugin(app.Plugins{lib}.dist, name);
                app.Plugins{lib}.dist(idx) = [];
            end
        end

        function add_plugin(app, name, t, type, lib, lib_version)
            s = struct('Name', string(name), 'Type', type, 'Lib', lib, 'LibVersion', lib_version);
            if t == 1
                idx = app.idx_plugin(app.Plugins{lib}.sys, name);
                if isempty(idx) || idx == 0
                    app.Plugins{lib}.sys(end+1) = s;
                else
                    app.Plugins{lib}.sys(idx) = s;
                end

            elseif t == 2
                idx = app.idx_plugin(app.Plugins{lib}.ctl, name);
                if isempty(idx) || idx == 0
                    app.Plugins{lib}.ctl(end+1) = s;
                else
                    app.Plugins{lib}.ctl(idx) = s;
                end            
            elseif t == 3
                idx = app.idx_plugin(app.Plugins{lib}.est, name);
                if isempty(idx) || idx == 0
                    app.Plugins{lib}.est(end+1) = s;
                else
                    app.Plugins{lib}.est(idx) = s;
                end
            elseif t == 4
                idx = app.idx_plugin(app.Plugins{lib}.dist, name);
                if isempty(idx) || idx == 0
                    app.Plugins{lib}.dist(end+1) = s;
                else
                    app.Plugins{lib}.dist(idx) = s;
                end
            end        
        end
        
        function results = get_selected_items(app, table)
            selection = table.Selection;
            if ~isempty(selection)
                results = unique(selection(:, 1));
                return
            end
            results = [];
        end
        
        function [table, data, t] = get_active_table(app)
            tab = app.TabGroup.SelectedTab;
            lib = app.get_active_library().Name;
            if strcmp(tab.Title, 'Systems')
                t = 1;
                table = app.SysTable;
                data = app.Plugins{lib}.sys;
            elseif strcmp(tab.Title, 'Controllers')
                t = 2;
                table = app.CtlTable;
                data = app.Plugins{lib}.ctl;
            elseif strcmp(tab.Title, 'Estimators')
                t = 3;
                table = app.EstTable;
                data = app.Plugins{lib}.est;
            elseif strcmp(tab.Title, 'Disturbances')
                t = 4;
                table = app.DistTable;
                data = app.Plugins{lib}.dist;
            else 
                t = 0;
                table = [];
                data = [];
            end
        end

        function add_to_context(app, path)
            lib = app.get_active_library();
            dest = app.get_lib_context_path(lib);
            
            copyfile(path, dest, "f")
            [~, name, ~] = fileparts(path);
            data.Path = fullfile(dest, name);
            uitreenode(app.Context, 'Text', 'NodeData', data);
        end
    

        function on_remove_selection_from_context(app, nodes)
            for i=1:length(nodes)
                n = nodes(i);
                if isfolder(n.NodeData.Path)
                    rmdir(n.NodeData.Path, "s");
                else
                    delete(n.NodeData.Path);
                end
                
            end
            delete(nodes)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function on_start(app)
            app.LocalLib.handle = ...
                get_or_create_component_library(fullfile(CSPath.get_app_registry_path(), 'local'));
            app.load_tables;

        end

        % Close request function: PluginManagerUIFigure
        function on_close(app, event)
            delete(app)
            
        end

        % Clicked callback: CtlTable, DistTable, EstTable, SysTable
        function on_table_click(app, event)

            if isempty(event.Source.Selection)
                app.UnregisterselectedcomponentsButton.Enable = 0;
            else
                app.UnregisterselectedcomponentsButton.Enable = 1;
            end
        end

        % Button pushed function: RegisternewcomponentButton
        function on_comp_register(app, event)
            try
                context_path = app.get_active_library().Path;
            catch
                error("Cannot register component. No library is selected");
            end
            [file, location] = uigetfile({'*.m;*.slx;*.py';}, ...
                'Select component file', context_path);
            if isnumeric(file)
                return
            end
            selpath = fullfile(location, file);

            if endsWith(file, '.slx')
                answer = inputdlg('Path', 'Component path');
                if isempty(answer)
                    return
                end
                selpath = strcat(selpath, ':', answer{1});
            end

            if is_file_valid_component(selpath)
                lib = app.get_active_library();
                p = get_plugin_info_from_file(selpath);
                if app.plugin_exists(p.Name, lib.Name)
                    warning(strcat("Cannot add component '", p.Name, "'. Component already exists"));
                    return
                end
                register_component(p, p.T, lib.Name, lib.Path);
                add_to_lib_manifest(p, p.T, lib.Name);
                lib_version = '0.1';
                app.add_plugin(p.Name, p.T, p.Type, lib.Name, lib_version);
                app.update_tables(app.Tree.SelectedNodes);
            end
        end

        % Button pushed function: UnregisterselectedcomponentsButton
        function on_comp_unregister(app, event)
            [table, data, t] = app.get_active_table();
            items = app.get_selected_items(table);
            lib = app.get_active_library();
            if length(items) > 1
                msg = 'Are you sure you want to delete multiple components?';
            elseif isscalar(items)
                sel = data(items).Name;
                msg = strcat("Are you sure you want to delete component '", ...
                    sel, "' from library '", lib.Name, "'?");
            else
                return
            end
            c = uiconfirm(app.PluginManagerUIFigure, msg, 'Confirm delete', 'Options', ["Yes", "No"]);            
            if strcmp(c, "No")
                return
            end
            
            for i=1:length(items)
                name = data(items(i)).Name;
                
                if is_library_locked(lib.Name)
                    error(strcat("Cannot remove component '", name, "'. ", ...
                        "Library is locked."));
                end
                unregister_component(name, lib.Name);
                remove_from_lib_manifest(name, t, lib.Name);
                app.remove_plugin(name, t, lib.Name);
                app.update_tables(app.Tree.SelectedNodes);
            end
        end

        % Button pushed function: CreatenewlibraryButton
        function on_create_new_library(app, event)
            answer = inputdlg('Name', 'New library name');
            if isempty(answer)
                return
            end
            handle = get_or_create_component_library(fullfile(CSPath.get_app_registry_path(), answer{1}), 1);
            app.TreeData(end+1) = uitreenode(app.Tree, 'Text', answer{1});
            app.Libs(end+1) = struct('Name', handle.name, 'Type', 'install', "Path", handle.path, 'Version', '0.0.1');
            app.Plugins{answer{1}} = empty_plugin_container();
        end

        % Button pushed function: ImportlibraryButton
        function on_register_library(app, event)
            
           
            [file, location] = uigetfile({'*.json';});
            if isnumeric(file)
                return
            end
            if ~strcmp(file, 'package.json')
                error('Selected file is not a library package file.');
            end
                        
            register_component_library(location, 0);
            app.load_tables;
        end

        % Button pushed function: UnregisterlibraryButton
        function on_unregister_library(app, event)
            lib = app.get_active_library().Name;
            idx = find(app.Tree.Children == app.Tree.SelectedNodes);
            app.Tree.SelectedNodes.Parent = [];
            app.TreeData(idx) = [];
            app.Plugins{lib} = [];
            app.Libs(idx) = [];
            remove_component_library(lib); 

        end

        % Button pushed function: ExportlibraryButton
        function on_export_library(app, event)
            lib = app.get_active_library().Name;
            export_component_library(lib); 
        end

        % Selection changed function: Tree
        function on_node_select(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            app.update_tables(selectedNodes);
            app.update_context(selectedNodes);
            if strcmp(selectedNodes.Text, 'csbenchlab')
                app.RegisternewcomponentButton.Enable = 'off';
                app.ExportlibraryButton.Enable = 'off';
                app.AddfileButton.Enable = 'off';
                app.AddfolderButton.Enable = 'off';
                app.RefreshlibraryButton.Enable = 'off';
            else
                app.RegisternewcomponentButton.Enable = 'on';
                app.ExportlibraryButton.Enable = 'on';
                app.AddfileButton.Enable = 'on';
                app.AddfolderButton.Enable = 'on';
                app.RefreshlibraryButton.Enable = 'on';
            end
            app.UnregisterselectedcomponentsButton.Enable = 'off';

        end

        % Clicked callback: Context
        function on_select_context_file(app, event)
            selectedNodes = app.Context.SelectedNodes;
            figure(app.PluginManagerUIFigure);
        end

        % Button pushed function: AddfileButton
        function on_add_file_to_context(app, event)
            [file, location] = uigetfile("MultiSelect", 'on');
            if isnumeric(file)
                return
            end
            if ~iscell(file)
                file = {file};
            end
            for i=1:length(file)
                app.add_to_context(fullfile(location, file{i}));
            end
        end

        % Button pushed function: AddfolderButton
        function on_add_folder_to_context(app, event)
            path = uigetdir("MultiSelect", 'on');
            if isnumeric(path)
                return
            end
            app.add_to_context(path);

        end

        % Key press function: PluginManagerUIFigure
        function on_key_press(app, event)
           key = event.Key;
           if strcmp(key, 'delete')
                nodes = app.Context.SelectedNodes;
                app.on_remove_selection_from_context(nodes);
                 
            end
            
        end

        % Button pushed function: DeleteButton
        function on_delete_from_context(app, event)
            nodes = app.Context.SelectedNodes;
            app.on_remove_selection_from_context(nodes);
        end

        % Button pushed function: LinklibraryButton
        function on_link_library(app, event)
            [file, location] = uigetfile({'*.json';});
            if isnumeric(file)
                return
            end
            if ~strcmp(file, 'package.json')
                error('Selected file is not a library package file.');
            end
                        
            register_component_library(location, 1);
            app.load_tables;
        end

        % Button pushed function: RefreshlibraryButton
        function on_refresh_library(app, event)
            try
                lib = app.get_active_library();
            catch
                error('No library selected...');
                
            end
            disp(strcat("Refreshing library '", lib.Name, "'..."))
            close_library(lib.Name);
            refresh_component_library(lib.Name);
            disp("Library refreshed");

            app.load_tables;
            app.update_tables(app.Tree.SelectedNodes);
        end

        % Button pushed function: OpencontextButton
        function on_open_context(app, event)
            lib = app.get_active_library();
            system(strcat("open ", lib.Path));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PluginManagerUIFigure = uifigure('Visible', 'off');
            app.PluginManagerUIFigure.Name = 'Plugin Manager';
            app.PluginManagerUIFigure.CloseRequestFcn = createCallbackFcn(app, @on_close, true);
            app.PluginManagerUIFigure.KeyPressFcn = createCallbackFcn(app, @on_key_press, true);
            app.GridLayout6 = uigridlayout(app.PluginManagerUIFigure);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.Tree = uitree(app.GridLayout7);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @on_node_select, true);
            app.LibrariesLabel = uilabel(app.GridLayout7);
            app.LibrariesLabel.Text = 'Libraries';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.CreatenewlibraryButton = uibutton(app.GridLayout8, 'push');
            app.CreatenewlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_create_new_library, true);
            app.CreatenewlibraryButton.Text = 'Create new library';
            app.ImportlibraryButton = uibutton(app.GridLayout8, 'push');
            app.ImportlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_register_library, true);
            app.ImportlibraryButton.Text = 'Import library';
            app.ExportlibraryButton = uibutton(app.GridLayout8, 'push');
            app.ExportlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_export_library, true);
            app.ExportlibraryButton.Text = 'Export library';
            app.UnregisterlibraryButton = uibutton(app.GridLayout8, 'push');
            app.UnregisterlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_unregister_library, true);
            app.UnregisterlibraryButton.Text = 'Unregister library';
            app.LinklibraryButton = uibutton(app.GridLayout8, 'push');
            app.LinklibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_link_library, true);
            app.LinklibraryButton.Text = 'Link library';
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.GridLayout12 = uigridlayout(app.GridLayout9);
            app.RegisternewcomponentButton = uibutton(app.GridLayout12, 'push');
            app.RegisternewcomponentButton.ButtonPushedFcn = createCallbackFcn(app, @on_comp_register, true);
            app.RegisternewcomponentButton.Text = 'Register new component';
            app.UnregisterselectedcomponentsButton = uibutton(app.GridLayout12, 'push');
            app.UnregisterselectedcomponentsButton.ButtonPushedFcn = createCallbackFcn(app, @on_comp_unregister, true);
            app.UnregisterselectedcomponentsButton.Enable = 'off';
            app.UnregisterselectedcomponentsButton.Text = 'Unregister selected components';
            app.RefreshlibraryButton = uibutton(app.GridLayout12, 'push');
            app.RefreshlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_refresh_library, true);
            app.RefreshlibraryButton.Text = 'Refresh library';
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.SysTab = uitab(app.TabGroup);
            app.SysTab.Title = 'Systems';
            app.GridLayout = uigridlayout(app.SysTab);
            app.SysTable = uitable(app.GridLayout);
            app.SysTable.ColumnName = {'Name'; 'Type'; 'Column 3'; 'Column 4'};
            app.SysTable.RowName = {};
            app.SysTable.ClickedFcn = createCallbackFcn(app, @on_table_click, true);
            app.CtlTab = uitab(app.TabGroup);
            app.CtlTab.Title = 'Controllers';
            app.GridLayout2 = uigridlayout(app.CtlTab);
            app.CtlTable = uitable(app.GridLayout2);
            app.CtlTable.ColumnName = {'Name'; 'Type'; 'Column 3'; 'Column 4'};
            app.CtlTable.RowName = {};
            app.CtlTable.ClickedFcn = createCallbackFcn(app, @on_table_click, true);
            app.EstTab = uitab(app.TabGroup);
            app.EstTab.Title = 'Estimators';
            app.GridLayout3 = uigridlayout(app.EstTab);
            app.EstTable = uitable(app.GridLayout3);
            app.EstTable.ColumnName = {'Name'; 'Type'; 'Column 3'; 'Column 4'};
            app.EstTable.RowName = {};
            app.EstTable.ClickedFcn = createCallbackFcn(app, @on_table_click, true);
            app.DistTab = uitab(app.TabGroup);
            app.DistTab.Title = 'Disturbances';
            app.GridLayout4 = uigridlayout(app.DistTab);
            app.DistTable = uitable(app.GridLayout4);
            app.DistTable.ColumnName = {'Name'; 'Type'; 'Column 3'; 'Column 4'};
            app.DistTable.RowName = {};
            app.DistTable.ClickedFcn = createCallbackFcn(app, @on_table_click, true);
            app.GridLayout10 = uigridlayout(app.GridLayout6);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.AddfileButton = uibutton(app.GridLayout11, 'push');
            app.AddfileButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_file_to_context, true);
            app.AddfileButton.Text = 'Add file';
            app.AddfolderButton = uibutton(app.GridLayout11, 'push');
            app.AddfolderButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_folder_to_context, true);
            app.AddfolderButton.Text = 'Add folder';
            app.DeleteButton = uibutton(app.GridLayout11, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @on_delete_from_context, true);
            app.DeleteButton.Text = 'Delete';
            app.OpencontextButton = uibutton(app.GridLayout11, 'push');
            app.OpencontextButton.ButtonPushedFcn = createCallbackFcn(app, @on_open_context, true);
            app.OpencontextButton.Text = 'Open context';
            app.Context = uitree(app.GridLayout10);
            app.Context.Multiselect = 'on';
            app.Context.ClickedFcn = createCallbackFcn(app, @on_select_context_file, true);
            % Show the figure after all components are created
            app.PluginManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plugin_manager_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PluginManagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @on_start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PluginManagerUIFigure)
        end
    end
end