classdef plugin_selector_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        PluginTree    matlab.ui.container.Tree
        Panel         matlab.ui.container.Panel
        CancelButton  matlab.ui.control.Button
        SelectButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        OnClosedHandle % Description
    end
    
    methods (Access = private)
        
        function update_plugins(app, plugins)
            a = 5;
            children = {};
            for i=1:height(plugins)
                data.type = plugins(i, 1);
                data.lib = plugins(i, 2);
                children{end+1} = uitreenode(app.PluginTree, 'Text', strcat(plugins(i, 1), ':', plugins(i, 2)), 'NodeData', data);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, plugin_type, on_closed_handle)
            app.OnClosedHandle = on_closed_handle;
            plugins = get_available_plugins(parse_comp_type(plugin_type));
            app.update_plugins(plugins);
        end

        % Button pushed function: SelectButton
        function on_plugin_select(app, event)
            
            selection = app.PluginTree.SelectedNodes;
            app.OnClosedHandle(selection);
            close(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function on_cancel(app, event)
            app.OnClosedHandle([]);
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.SelectButton = uibutton(app.Panel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @on_plugin_select, true);
            app.SelectButton.Text = 'Select';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @on_cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.PluginTree = uitree(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plugin_selector_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end