classdef select_var_from_workspace_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        ListBox       matlab.ui.control.ListBox
        GridLayout2   matlab.ui.container.GridLayout
        CancelButton  matlab.ui.control.Button
        SelectButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        BaseVars % Description
        BaseVarTypes
        VarType
        OnClosedHandle
    end
    
    methods (Access = private)
        
        function results = load_variables(app)
            app.BaseVars = evalin('base', 'who');
            app.ListBox.Items = {};
            for i=1:length(app.BaseVars)
                app.BaseVarTypes(end+1) = string(class(evalin('base', app.BaseVars{i})));
                app.ListBox.Items{end+1} = app.BaseVars{i};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, type, on_closed_handle)
            app.VarType = type;    
            app.load_variables();
            app.OnClosedHandle = on_closed_handle;
        end

        % Button pushed function: SelectButton
        function on_select_var(app, event)
            selection = app.ListBox.Value;
            app.OnClosedHandle(selection);
            close(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function on_cancel(app, event)
            app.OnClosedHandle([]);
            close(app.UIFigure);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SelectButton = uibutton(app.GridLayout2, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @on_select_var, true);
            app.SelectButton.Text = 'Select';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @on_cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {''};
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = select_var_from_workspace_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end