classdef selector_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        Tree          matlab.ui.container.Tree
        ListBox       matlab.ui.control.ListBox
        GridLayout2   matlab.ui.container.GridLayout
        CancelButton  matlab.ui.control.Button
        SelectButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        OnClosedHandle
        SelectItems
        IsList
    end
    
    methods (Access = private)
        
        function results = display_items(app, items)
            first = app.SelectItems(items(1));
            selected = strings(length(items), width(first));
            selected(1, :) = first;
            if isa(items, 'cell')
                for i=2:length(items)
                    selected(i, :) = app.SelectItems(items{i});
                end
            else
                for i=2:length(items)
                    selected(i, :) = app.SelectItems(items(i));
                end
            end
            if width(selected) == 1
                app.Tree.Visible = 'off';
                app.ListBox.Visible = 'on';
                app.IsList = 1;
                app.ListBox.Items = selected;
                app.ListBox.ItemsData = items;  
                return
            end
            
            app.IsList = 0;
            app.Tree.Visible = 'on';
            app.ListBox.Visible = 'off';

            un = unique(selected(:, 1));
            nodes = matlab.ui.container.TreeNode.empty;
            for i=1:length(un)
                nodes(end+1) = uitreenode(app.Tree, 'Text', un(i));
            end
            item_nodes = matlab.ui.container.TreeNode.empty;
            if isa(items, 'cell')
                for i=1:length(selected)
                    idx = strcmp(nodes.Text, selected(i, 1));
                    item_nodes(end+1) = uitreenode(nodes(idx), 'Text', selected(i, 2), 'NodeData', items{i});
                end
            else
                for i=1:length(selected)
                    idx = strcmp(nodes.Text, selected(i, 1));
                    item_nodes(end+1) = uitreenode(nodes(idx), 'Text', selected(i, 2), 'NodeData', items(i));
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, item_list, on_closed_handle, select_items)
            app.OnClosedHandle = on_closed_handle;
            app.IsList = 1;
            if exist('select_items', 'var')
                app.SelectItems = select_items;
            else
                app.SelectItems = @(x) x;
            end
            app.display_items(item_list);
        end

        % Button pushed function: SelectButton
        function on_select_var(app, event)
            if app.IsList
                idx = app.ListBox.ValueIndex;
                if isa(app.ListBox.ItemsData, 'cell')
                    app.OnClosedHandle(app.ListBox.ItemsData{idx});
                else
                    app.OnClosedHandle(app.ListBox.ItemsData(idx));
                end
            else
                selection = app.Tree.SelectedNodes;
                if selection.Parent == app.Tree
                    error('Select plugin type...');
                else
                    app.OnClosedHandle(selection.NodeData);
                end
            end
            close(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function on_cancel(app, event)
            app.OnClosedHandle([]);
            close(app.UIFigure);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SelectButton = uibutton(app.GridLayout2, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @on_select_var, true);
            app.SelectButton.Text = 'Select';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @on_cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {''};
            app.ListBox.Value = {};
            app.Tree = uitree(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selector_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end